classdef SLM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        SLMregioneditorPanel            matlab.ui.container.Panel
        BeamdumpYEditField              matlab.ui.control.NumericEditField
        BeamdumpXEditField              matlab.ui.control.NumericEditField
        ZOsuppWEditField                matlab.ui.control.NumericEditField
        ZOsuppphaseEditField            matlab.ui.control.NumericEditField
        SelectregionDropDownLabel       matlab.ui.control.Label
        RegionnameEditFieldLabel        matlab.ui.control.Label
        WavelengthnmEditField_2Label    matlab.ui.control.Label
        LUTcorrectionLabel              matlab.ui.control.Label
        minEditFieldLabel               matlab.ui.control.Label
        maxEditFieldLabel               matlab.ui.control.Label
        minEditField_2Label             matlab.ui.control.Label
        maxEditField_2Label             matlab.ui.control.Label
        XYZaffinetransformLabel         matlab.ui.control.Label
        AOcorrectionDropDownLabel       matlab.ui.control.Label
        EffectiveNALabel                matlab.ui.control.Label
        BeamdiameterpixLabel            matlab.ui.control.Label
        XoffsetEditFieldLabel           matlab.ui.control.Label
        YoffsetEditFieldLabel           matlab.ui.control.Label
        ZoffsetEditFieldLabel           matlab.ui.control.Label
        XZcorrEditFieldLabel            matlab.ui.control.Label
        YZcorrEditFieldLabel            matlab.ui.control.Label
        YZcorrEditField                 matlab.ui.control.NumericEditField
        XZcorrEditField                 matlab.ui.control.NumericEditField
        ZoffsetEditField                matlab.ui.control.NumericEditField
        YoffsetEditField                matlab.ui.control.NumericEditField
        XoffsetEditField                matlab.ui.control.NumericEditField
        BeamxyzandaxialcorrectionsLabel  matlab.ui.control.Label
        PointweightcorrectionDropDownLabel  matlab.ui.control.Label
        PointweightcorrectionDropDown   matlab.ui.control.DropDown
        AOcorrectionDropDown            matlab.ui.control.DropDown
        XYZaffinetransformDropDown      matlab.ui.control.DropDown
        LUTcorrectionDropDown           matlab.ui.control.DropDown
        regionBeamDiameterEditField     matlab.ui.control.NumericEditField
        regionZerooutsidephasediameterCheckBox  matlab.ui.control.CheckBox
        BeamdumpXEditFieldLabel         matlab.ui.control.Label
        BeamdumpYEditFieldLabel         matlab.ui.control.Label
        PhasediameterpixLabel           matlab.ui.control.Label
        regionPhaseDiameterEditField    matlab.ui.control.NumericEditField
        regionEffectiveNAEditField      matlab.ui.control.NumericEditField
        regionWavelengthnmEditField     matlab.ui.control.NumericEditField
        regionwidthmaxEditField         matlab.ui.control.NumericEditField
        regionwidthminEditField         matlab.ui.control.NumericEditField
        regionwidthLabel                matlab.ui.control.Label
        regionheightmaxEditField        matlab.ui.control.NumericEditField
        regionheightminEditField        matlab.ui.control.NumericEditField
        regionheightLabel               matlab.ui.control.Label
        RegionnameEditField             matlab.ui.control.EditField
        DeleteregButton                 matlab.ui.control.Button
        AddregButton                    matlab.ui.control.Button
        SaveregButton                   matlab.ui.control.Button
        ZOsuppphaseEditFieldLabel       matlab.ui.control.Label
        ZOsuppWEditFieldLabel           matlab.ui.control.Label
        SelectRegionDropDown            matlab.ui.control.DropDown
        SaveopsButton                   matlab.ui.control.Button
        LoadopsButton                   matlab.ui.control.Button
        LoadcalibrationsButton          matlab.ui.control.Button
        AOcorrZtoleranceEditField       matlab.ui.control.NumericEditField
        SLMwidthEditFieldLabel          matlab.ui.control.Label
        SLMheightEditFieldLabel         matlab.ui.control.Label
        ObjectiveRIEditFieldLabel       matlab.ui.control.Label
        NIDAQdeviceEditFieldLabel       matlab.ui.control.Label
        DAQcounterchannelEditFieldLabel  matlab.ui.control.Label
        DAQAIchannelEditFieldLabel      matlab.ui.control.Label
        LUTDropDownLabel                matlab.ui.control.Label
        xpixelsEditFieldLabel           matlab.ui.control.Label
        ypixelsEditFieldLabel           matlab.ui.control.Label
        ypixelsEditField                matlab.ui.control.NumericEditField
        xpixelsEditField                matlab.ui.control.NumericEditField
        ZoomEditField                   matlab.ui.control.NumericEditField
        FOVsizeumEditField              matlab.ui.control.NumericEditField
        ZoomEditFieldLabel              matlab.ui.control.Label
        DAQAOchannelEditFieldLabel      matlab.ui.control.Label
        AOcorrZtoleranceEditFieldLabel  matlab.ui.control.Label
        FOVsizeumEditFieldLabel         matlab.ui.control.Label
        ObjectiveDropDown               matlab.ui.control.DropDown
        DAQAOchannelEditField           matlab.ui.control.NumericEditField
        DAQAIchannelEditField           matlab.ui.control.NumericEditField
        DAQcounterchannelEditField      matlab.ui.control.NumericEditField
        NIDAQdeviceEditField            matlab.ui.control.EditField
        SLMtypeDropDownLabel            matlab.ui.control.Label
        ObjectiveDropDownLabel          matlab.ui.control.Label
        TubelengthEditField             matlab.ui.control.NumericEditField
        ObjectiveRIEditField            matlab.ui.control.NumericEditField
        TubelengthEditFieldLabel        matlab.ui.control.Label
        SLMwidthEditField               matlab.ui.control.NumericEditField
        SLMheightEditField              matlab.ui.control.NumericEditField
        LUTDropDown                     matlab.ui.control.DropDown
        SLMtypeDropDown                 matlab.ui.control.DropDown
        OutputSLMexternaltriggerCheckBox  matlab.ui.control.CheckBox
        XYZpatternsTab                  matlab.ui.container.Tab
        UIImagePhaseTable               matlab.ui.control.Table
        SaveXYZmatButton                matlab.ui.control.Button
        patterneditorButton             matlab.ui.control.Button
        OptimizeW_estacrosspatButton    matlab.ui.control.Button
        pointsizeumEditField            matlab.ui.control.NumericEditField
        OptimizeW_estwithinpatButton    matlab.ui.control.Button
        EstimateIntensityButton         matlab.ui.control.Button
        ViewpredictedPSFButton          matlab.ui.control.Button
        fftzoomed20xCheckBox            matlab.ui.control.CheckBox
        fftampsquaredCheckBox           matlab.ui.control.CheckBox
        fftdefocusumEditField           matlab.ui.control.NumericEditField
        ViewselectedpatternfftButton    matlab.ui.control.Button
        ViewselectedpatternButton       matlab.ui.control.Button
        UploadselectedpatternButton     matlab.ui.control.Button
        ClearallButton                  matlab.ui.control.Button
        ViewselectedphasefftButton      matlab.ui.control.Button
        ViewselectedphaseButton         matlab.ui.control.Button
        RemoveselectedButton            matlab.ui.control.Button
        UploadselectedphaseButton       matlab.ui.control.Button
        AddtohologramButton             matlab.ui.control.Button
        CreatecustompointhologramPanel  matlab.ui.container.Panel
        UploadHologramButton            matlab.ui.control.Button
        ViewHologramButtonMultiplane    matlab.ui.control.Button
        WeightEditField                 matlab.ui.control.EditField
        PointweightLabel                matlab.ui.control.Label
        ZoffsetumEditField              matlab.ui.control.EditField
        DefocusLabel                    matlab.ui.control.Label
        PatternnumberEditFieldLabel     matlab.ui.control.Label
        XdisplacementEditFieldLabel     matlab.ui.control.Label
        YdisplacementEditFieldLabel     matlab.ui.control.Label
        YdisplacementEditField          matlab.ui.control.EditField
        XdisplacementEditField          matlab.ui.control.EditField
        XYdisplacementLabel             matlab.ui.control.Label
        ZoffsetumEditFieldLabel         matlab.ui.control.Label
        WeightEditFieldLabel            matlab.ui.control.Label
        PatternnumberEditField          matlab.ui.control.EditField
        IntensitytargetEditField        matlab.ui.control.EditField
        IntensitytargetEditFieldLabel   matlab.ui.control.Label
        SavegroupButton                 matlab.ui.control.Button
        DeletegroupButton               matlab.ui.control.Button
        AddgroupButton                  matlab.ui.control.Button
        GroupnameEditField              matlab.ui.control.EditField
        PatterngroupDropDown            matlab.ui.control.DropDown
        fftdefocusumEditFieldLabel      matlab.ui.control.Label
        PatterngroupDropDownLabel       matlab.ui.control.Label
        GroupnameEditFieldLabel         matlab.ui.control.Label
        pointsizeumEditFieldLabel       matlab.ui.control.Label
        XYZpatalgotithmDropDownLabel    matlab.ui.control.Label
        XYZpatalgotithmDropDown         matlab.ui.control.DropDown
        MakedisksCheckBox               matlab.ui.control.CheckBox
        I_targI22PCheckBox              matlab.ui.control.CheckBox
        I_estI22PCheckBox               matlab.ui.control.CheckBox
        PlotestimationCheckBox          matlab.ui.control.CheckBox
        PlotwoptimizationCheckBox       matlab.ui.control.CheckBox
        UpdateW_estButton               matlab.ui.control.Button
        LoadXYZmatButton                matlab.ui.control.Button
        ImportTableButton               matlab.ui.control.Button
        ImportROIsButton                matlab.ui.control.Button
        ExportTableButton               matlab.ui.control.Button
        ScanTab                         matlab.ui.container.Tab
        GroupscanPanel                  matlab.ui.container.Panel
        ImagingEOFcounterchantriggeredPanel  matlab.ui.container.Panel
        PlotSLMupdateratesCheckBox      matlab.ui.control.CheckBox
        ImagingReadyLamp                matlab.ui.control.Lamp
        InitializeimagingButton         matlab.ui.control.StateButton
        NumVolumesEditField             matlab.ui.control.NumericEditField
        PatterngroupLabel               matlab.ui.control.Label
        PatternDropDownCtr              matlab.ui.control.DropDown
        NumVolumesEditFieldLabel        matlab.ui.control.Label
        TriggertypeDropDownLabel        matlab.ui.control.Label
        TriggertypeDropDown             matlab.ui.control.DropDown
        StimulationAIinputVpatternnumPanel  matlab.ui.container.Panel
        PatterngroupLabel_2             matlab.ui.control.Label
        PatternDropDownAI               matlab.ui.control.DropDown
        stimcontrolsourceButtonGroup    matlab.ui.container.ButtonGroup
        CustomstimButton                matlab.ui.control.ToggleButton
        AIinputButton                   matlab.ui.control.ToggleButton
        CustomstimprotocolPanel         matlab.ui.container.Panel
        patternrepeatsEditFieldLabel    matlab.ui.control.Label
        patternorderDropDownLabel       matlab.ui.control.Label
        patterndurationEditFieldLabel   matlab.ui.control.Label
        patternintervalEditFieldLabel   matlab.ui.control.Label
        patternintervaljitterEditFieldLabel  matlab.ui.control.Label
        patternintervaljitterEditField  matlab.ui.control.NumericEditField
        patternrepeatsEditField         matlab.ui.control.NumericEditField
        patternintervalEditField        matlab.ui.control.NumericEditField
        patterndurationEditField        matlab.ui.control.NumericEditField
        initialdelayEditFieldLabel      matlab.ui.control.Label
        initialdelayEditField           matlab.ui.control.NumericEditField
        PlotexamplestimtraceButton      matlab.ui.control.Button
        patternorderDropDown            matlab.ui.control.DropDown
        GenerateHologramTab             matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        PistonTab                       matlab.ui.container.Tab
        GeneratepistonButton            matlab.ui.control.Button
        PixelValueLabel                 matlab.ui.control.Label
        BlankPixelValueEditField        matlab.ui.control.NumericEditField
        BlazedGratingTab                matlab.ui.container.Tab
        GenerateBlazedGratingButton     matlab.ui.control.Button
        PeriodEditFieldLabel            matlab.ui.control.Label
        BlazReverseCheckBox             matlab.ui.control.CheckBox
        BlazHorizontalCheckBox          matlab.ui.control.CheckBox
        BlazIncreasingCheckBox          matlab.ui.control.CheckBox
        BlazPeriodEditField             matlab.ui.control.NumericEditField
        StripesTab                      matlab.ui.container.Tab
        GenerateStripesButton           matlab.ui.control.Button
        PixelsPerStripeLabel            matlab.ui.control.Label
        HorizontalstripeCheckBox        matlab.ui.control.CheckBox
        StripePixelPerStripeEditField   matlab.ui.control.NumericEditField
        PixelValueEditFieldLabel        matlab.ui.control.Label
        GrayEditFieldLabel              matlab.ui.control.Label
        StripeGrayEditField             matlab.ui.control.NumericEditField
        StripePixelValueEditField       matlab.ui.control.NumericEditField
        DefocusTab                      matlab.ui.container.Tab
        GeneratedefocusButton           matlab.ui.control.Button
        DefocusdistumLabel              matlab.ui.control.Label
        DeficusWeightEditField          matlab.ui.control.NumericEditField
        CrossTab                        matlab.ui.container.Tab
        GenerateCrossButton             matlab.ui.control.Button
        PixelValueEditFieldLabel_2      matlab.ui.control.Label
        GrayEditFieldLabel_2            matlab.ui.control.Label
        AngleradLabel                   matlab.ui.control.Label
        CrossAngleEditField             matlab.ui.control.NumericEditField
        CrossGrayEditField              matlab.ui.control.NumericEditField
        CrossPixelValueEditField        matlab.ui.control.NumericEditField
        FresnelLensTab                  matlab.ui.container.Tab
        GenerateFresnelLensButton       matlab.ui.control.Button
        CenterXEditFieldLabel           matlab.ui.control.Label
        CenterYEditFieldLabel           matlab.ui.control.Label
        RadiusEditFieldLabel            matlab.ui.control.Label
        PowerEditFieldLabel             matlab.ui.control.Label
        FresHorizontalCheckBox          matlab.ui.control.CheckBox
        FresCylindricalCheckBox         matlab.ui.control.CheckBox
        FresPowerEditField              matlab.ui.control.NumericEditField
        FresRadiusEditField             matlab.ui.control.NumericEditField
        FresCenterYEditField            matlab.ui.control.NumericEditField
        FresCenterXEditField            matlab.ui.control.NumericEditField
        ZernikeTab                      matlab.ui.container.Tab
        GenerateZernikeButton           matlab.ui.control.Button
        CenterXEditField_2Label         matlab.ui.control.Label
        CenterYEditField_5Label         matlab.ui.control.Label
        RadiusEditFieldLabel_2          matlab.ui.control.Label
        PistonEditFieldLabel            matlab.ui.control.Label
        TiltXEditFieldLabel             matlab.ui.control.Label
        TiltYEditFieldLabel             matlab.ui.control.Label
        PowerEditFieldLabel_2           matlab.ui.control.Label
        AstigXEditFieldLabel            matlab.ui.control.Label
        AstigYEditFieldLabel            matlab.ui.control.Label
        ComaXEditFieldLabel             matlab.ui.control.Label
        ComaYEditFieldLabel             matlab.ui.control.Label
        PrimarySphericalEditFieldLabel  matlab.ui.control.Label
        TrefoilXEditFieldLabel          matlab.ui.control.Label
        TrefoilYEditFieldLabel          matlab.ui.control.Label
        SecondaryAstigXEditFieldLabel   matlab.ui.control.Label
        SecondaryAstigYEditFieldLabel   matlab.ui.control.Label
        SecondaryComaXEditFieldLabel    matlab.ui.control.Label
        SecondaryComaYEditFieldLabel    matlab.ui.control.Label
        SecondarySphericalEditFieldLabel  matlab.ui.control.Label
        TetrafoilXEditFieldLabel        matlab.ui.control.Label
        TetrafoilYEditFieldLabel        matlab.ui.control.Label
        TertiarySphericalEditFieldLabel  matlab.ui.control.Label
        QuaternarySphericalEditFieldLabel  matlab.ui.control.Label
        QuaternarySphericalEditField    matlab.ui.control.NumericEditField
        TertiarySphericalEditField      matlab.ui.control.NumericEditField
        TetrafoilYEditField             matlab.ui.control.NumericEditField
        TetrafoilXEditField             matlab.ui.control.NumericEditField
        SecondarySphericalEditField     matlab.ui.control.NumericEditField
        SecondaryComaYEditField         matlab.ui.control.NumericEditField
        SecondaryComaXEditField         matlab.ui.control.NumericEditField
        SecondaryAstigYEditField        matlab.ui.control.NumericEditField
        SecondaryAstigXEditField        matlab.ui.control.NumericEditField
        TrefoilYEditField               matlab.ui.control.NumericEditField
        TrefoilXEditField               matlab.ui.control.NumericEditField
        PrimarySphericalEditField       matlab.ui.control.NumericEditField
        ComaYEditField                  matlab.ui.control.NumericEditField
        ComaXEditField                  matlab.ui.control.NumericEditField
        AstigYEditField                 matlab.ui.control.NumericEditField
        AstigXEditField                 matlab.ui.control.NumericEditField
        PowerEditField                  matlab.ui.control.NumericEditField
        TiltYEditField                  matlab.ui.control.NumericEditField
        TiltXEditField                  matlab.ui.control.NumericEditField
        PistonEditField                 matlab.ui.control.NumericEditField
        RadiusEditField                 matlab.ui.control.NumericEditField
        CenterYEditField                matlab.ui.control.NumericEditField
        CenterXEditField                matlab.ui.control.NumericEditField
        CustomphaseTab                  matlab.ui.container.Tab
        ImagepathEditFieldLabel         matlab.ui.control.Label
        PhaseformatDropDownLabel        matlab.ui.control.Label
        PhaseformatDropDown             matlab.ui.control.DropDown
        LoadimageButton                 matlab.ui.control.Button
        BrowseimageButton               matlab.ui.control.Button
        ImagepathEditField              matlab.ui.control.EditField
        UploadHologramButtonGenerate    matlab.ui.control.Button
        UIAxesGenerateHologram          matlab.ui.control.UIAxes
        UIAxesColorLimits               matlab.ui.control.UIAxes
        AdaptiveOpticsmodalTab          matlab.ui.container.Tab
        AOautooptimizationscanPanel     matlab.ui.container.Panel
        NumscanframesOpEditField        matlab.ui.control.NumericEditField
        ContinuescanCheckBox            matlab.ui.control.CheckBox
        StartoptimizationButton         matlab.ui.control.StateButton
        PostscandelayEditField          matlab.ui.control.NumericEditField
        BeadwindowsizeEditField         matlab.ui.control.NumericEditField
        PlotextradeetsCheckBox          matlab.ui.control.CheckBox
        PlotprogressCheckBox            matlab.ui.control.CheckBox
        NumiterationsSpinner            matlab.ui.control.Spinner
        DecresegradntimesEditField      matlab.ui.control.NumericEditField
        ScanallcorreverynframesEditField  matlab.ui.control.NumericEditField
        RefocussplinesmparamEditField   matlab.ui.control.NumericEditField
        RefocusnumstepsEditField        matlab.ui.control.NumericEditField
        RefocusdistumEditField          matlab.ui.control.NumericEditField
        RefocuseverynframesEditField    matlab.ui.control.NumericEditField
        OptimizationmethodDropDown      matlab.ui.control.DropDown
        ScanframesdirpathEditField      matlab.ui.control.EditField
        NumiterationsSpinnerLabel       matlab.ui.control.Label
        ScanframesdirpathEditFieldLabel  matlab.ui.control.Label
        NumscanframesLabel_2            matlab.ui.control.Label
        BeadwindowsizeEditFieldLabel    matlab.ui.control.Label
        PostscandelayEditFieldLabel     matlab.ui.control.Label
        OptimizationmethodDropDownLabel  matlab.ui.control.Label
        RefocuseverynframesEditFieldLabel  matlab.ui.control.Label
        ScanallcorreverynframesEditFieldLabel  matlab.ui.control.Label
        DecresegradntimesEditFieldLabel  matlab.ui.control.Label
        RefocusdistumEditFieldLabel     matlab.ui.control.Label
        RefocusnumstepsEditFieldLabel   matlab.ui.control.Label
        RefocussplinesmparamEditFieldLabel  matlab.ui.control.Label
        ParamsPanel                     matlab.ui.container.Panel
        SavefiletagEditFieldLabel       matlab.ui.control.Label
        ScanspermodeEditFieldLabel      matlab.ui.control.Label
        ScanspermodeEditField           matlab.ui.control.NumericEditField
        ShufflemodesCheckBox            matlab.ui.control.CheckBox
        SavefiletagEditField            matlab.ui.control.EditField
        ZernikeListTable                matlab.ui.control.Table
        ZernikeModestoscanadjustweightsifneededLabel  matlab.ui.control.Label
        FillzerniketablePanel           matlab.ui.container.Panel
        FillmodestableButton            matlab.ui.control.Button
        AOignoreallsphericalCheckBox    matlab.ui.control.CheckBox
        MaxZnEditFieldLabel             matlab.ui.control.Label
        WeightrangeEditFieldLabel       matlab.ui.control.Label
        NumwstepsEditFieldLabel         matlab.ui.control.Label
        AOignoresphericalmodeCheckBox   matlab.ui.control.CheckBox
        WregfactorEditField             matlab.ui.control.NumericEditField
        WsplinesmparamEditField         matlab.ui.control.NumericEditField
        NumwstepsEditField              matlab.ui.control.NumericEditField
        WeightrangeEditField            matlab.ui.control.NumericEditField
        MaxZnEditField                  matlab.ui.control.NumericEditField
        MinZnEditFieldLabel             matlab.ui.control.Label
        MinZnEditField                  matlab.ui.control.NumericEditField
        WsplinesmparamEditFieldLabel    matlab.ui.control.Label
        WregfactorLabel                 matlab.ui.control.Label
        TestHologramandweightsPanel     matlab.ui.container.Panel
        PlotHologramButton              matlab.ui.control.Button
        UploadHologramButton_2          matlab.ui.control.Button
        ModeindexEditFieldLabel         matlab.ui.control.Label
        weightEditFieldLabel            matlab.ui.control.Label
        weightEditField                 matlab.ui.control.NumericEditField
        ModeindexEditField              matlab.ui.control.NumericEditField
        AOregularscanzernikepolPanel    matlab.ui.container.Panel
        NumscanvolumesEditFieldLabel    matlab.ui.control.Label
        NumscanvolumesEditField         matlab.ui.control.NumericEditField
        NumscanframesLabel              matlab.ui.control.Label
        NumscanframesEditField          matlab.ui.control.NumericEditField
        ZernikeReadyLamp                matlab.ui.control.Lamp
        ScanZernikeButton               matlab.ui.control.StateButton
        XYZalgorithmTab                 matlab.ui.container.Tab
        DiskradiusumEditFieldLabel      matlab.ui.control.Label
        DiskradiusumEditField           matlab.ui.control.NumericEditField
        SLMimagepredictionwithfftPanel  matlab.ui.container.Panel
        UsegaussianbeamampCheckBox      matlab.ui.control.CheckBox
        MeadowlarkGSalgorithmPanel      matlab.ui.container.Panel
        GSnumiterationsEditFieldLabel   matlab.ui.control.Label
        GSnumiterationsEditField        matlab.ui.control.NumericEditField
        GSzfactorEditFieldLabel         matlab.ui.control.Label
        GSzfactorEditField              matlab.ui.control.NumericEditField
        ImageGenverEditFieldLabel       matlab.ui.control.Label
        ImageGenverEditField            matlab.ui.control.EditField
        SLMCalibrationTab               matlab.ui.container.Tab
        LUTcalibrationdataPanel         matlab.ui.container.Panel
        BitDepthEditFieldLabel          matlab.ui.control.Label
        BitDepthEditField               matlab.ui.control.NumericEditField
        NumRegionsEditFieldLabel        matlab.ui.control.Label
        NumRegionsEditField             matlab.ui.control.NumericEditField
        PixelsPerStripeEditFieldLabel   matlab.ui.control.Label
        PixelsPerStripeEditField        matlab.ui.control.NumericEditField
        AnalysissavefilenameEditFieldLabel  matlab.ui.control.Label
        AnalysissavefilenameEditField   matlab.ui.control.EditField
        RunLUTcalibrationButton         matlab.ui.control.StateButton
        AnalyzeLUTdataButton            matlab.ui.control.Button
        RunLUTcalibReadyLamp            matlab.ui.control.Lamp
        BaselinepixelvalueEditFieldLabel  matlab.ui.control.Label
        BaselinepixelvalueEditField     matlab.ui.control.NumericEditField
        TotalframesEditFieldLabel       matlab.ui.control.Label
        TotalframesEditField            matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        EndofframeButton                matlab.ui.control.ToggleButton
        TLDCcamButton                   matlab.ui.control.ToggleButton
        LateralcalibrationButton        matlab.ui.control.Button
        PointWeightcorrectionPanel      matlab.ui.container.Panel
        PlotpwcorrectionButton          matlab.ui.control.Button
        smoothstdEditFieldLabel         matlab.ui.control.Label
        PWsqrt2pCheckBox                matlab.ui.control.CheckBox
        MincorrthreshEditFieldLabel     matlab.ui.control.Label
        PWmincorrthreshEditField        matlab.ui.control.NumericEditField
        PWsmoothstdEditField            matlab.ui.control.NumericEditField
        AOPanel                         matlab.ui.container.Panel
        IgnoreallsphericalCheckBox      matlab.ui.control.CheckBox
        PlotextraCheckBox               matlab.ui.control.CheckBox
        modestofitEditFieldLabel        matlab.ui.control.Label
        FitmethodDropDownLabel          matlab.ui.control.Label
        splinesmparam01EditFieldLabel   matlab.ui.control.Label
        PlotfitCheckBox                 matlab.ui.control.CheckBox
        CompensatezCheckBox             matlab.ui.control.CheckBox
        Ignore0CheckBox                 matlab.ui.control.CheckBox
        Constrainz0CheckBox             matlab.ui.control.CheckBox
        save_fit_weightsCheckBox        matlab.ui.control.CheckBox
        splinesmparam01EditField        matlab.ui.control.NumericEditField
        modestofitEditField             matlab.ui.control.EditField
        FitmethodDropDown               matlab.ui.control.DropDown
        FitAOcorretionsButton           matlab.ui.control.Button
        ZcompthreshumEditFieldLabel     matlab.ui.control.Label
        ZcompthreshumEditField          matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        CloseallfigsButton              matlab.ui.control.Button
        SavecurrentlutButton            matlab.ui.control.Button
        ViewcurrentlutButton            matlab.ui.control.Button
        ViewcurrentAOButton             matlab.ui.control.Button
        ViewcurrentphaseButton          matlab.ui.control.Button
        BlankSLMButton                  matlab.ui.control.Button
        CurrentregionDropDown           matlab.ui.control.DropDown
        ZOsuppressionLamp               matlab.ui.control.Lamp
        ApplyZOsuppressionButton        matlab.ui.control.StateButton
        PWcorrectionLamp                matlab.ui.control.Lamp
        ApplyPWcorrectionButton         matlab.ui.control.StateButton
        InitializeAOLamp                matlab.ui.control.Lamp
        ApplyAOcorrectionButton         matlab.ui.control.StateButton
        XYZcalibrationLamp              matlab.ui.control.Lamp
        ApplyXYZcalibrationButton       matlab.ui.control.StateButton
        InitializeDAQLamp               matlab.ui.control.Lamp
        InitializeDAQButton             matlab.ui.control.Button
        ActivateSLMLamp                 matlab.ui.control.Lamp
        ActivateSLMButton               matlab.ui.control.StateButton
        CurrentregionDropDownLabel      matlab.ui.control.Label
        ViewcurrentimageButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        SLM_ops; % Description
        region_list;
        xyz_patterns;
        region_obj_params;
        lut_list;
        lut_corrections_list;
        GUI_buffer;
        SLM_phase;
        SLM_phase_corr;
        SLM_phase_corr_lut;
        SLM_image_pointer;
        SLM_gh_phase_preview;
        SLM_image_gh_climits;
        GUI_ops;
        current_SLM_coord;
        current_SLM_AO_Image = [];
        SLM_blank_phase;
        SLM_blank_pointer;
        SLM_phase_plot;
        hologram_fig_plot;
        UIImagePhaseTableSelection;
        DAQ_session;
        DAQ_ai_chan;
        AO_ignore_modes_list = [1, 2, 3];
        AO_last_zernike_data = cell(2,1);
        AO_last_LUT_data = cell(2,1);
        AO_correction_data = [];
        fov_im;
        pattern_editor_data;
        pp_ops;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GUI_dir = fileparts(mfilename('fullpath'));
            %pwd2 = fileparts(which('SLM_control_GUI.mlapp'));
            
            addpath(GUI_dir);
            if exist([GUI_dir '\SLM_GUI_funcions'], 'dir')
                addpath(genpath([GUI_dir '\SLM_GUI_funcions']));
                %addpath([pwd2 '\SLM_GUI_funcions']);
            else
                error('RAFA: You need to move to SLM_microscope_GUI directory and reopen GUI!!!');
            end 
            f_darik_gui_startup(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            f_sg_gui_close(app);
        end

        % Value changed function: ActivateSLMButton
        function ActivateSLMButtonValueChanged(app, event)
            f_sg_button_activate_SLM(app);
        end

        % Button pushed function: InitializeDAQButton
        function InitializeDAQButtonPushed(app, event)
            f_sg_initialize_DAQ(app);
        end

        % Value changed function: ApplyXYZcalibrationButton
        function ApplyXYZcalibrationButtonValueChanged(app, event)
            f_sg_apply_xyz_calibration(app);
        end

        % Value changed function: ApplyAOcorrectionButton
        function ApplyAOcorrectionButtonValueChanged(app, event)
            f_sg_apply_AO_correction_button(app);
        end

        % Value changed function: ApplyPWcorrectionButton
        function ApplyPWcorrectionButtonValueChanged(app, event)
            f_sg_apply_PW_correction_button(app);
        end

        % Value changed function: ApplyZOsuppressionButton
        function ApplyZOsuppressionButtonValueChanged(app, event)
            f_sg_apply_ZO_suppression_button(app);
        end

        % Button pushed function: BlankSLMButton
        function BlankSLMButtonPushed(app, event)
            f_sg_button_blank_display(app);
        end

        % Button pushed function: GeneratepistonButton
        function GenerateBlankLensButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'piston');
        end

        % Button pushed function: GenerateFresnelLensButton
        function GenerateFresnelLensButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'fresnel');
        end

        % Button pushed function: GenerateBlazedGratingButton
        function GenerateBlazedGratingButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'blazed');
        end

        % Button pushed function: GenerateStripesButton
        function GenerateStripesButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'stripes');
        end

        % Button pushed function: GenerateZernikeButton
        function GenerateZernikeButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'zernike');
        end

        % Button pushed function: GeneratedefocusButton
        function GeneratedefocusButtonPushed(app, event)
            f_sg_gh_defocus(app);
        end

        % Button pushed function: GenerateCrossButton
        function GenerateCrossButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'cross');
        end

        % Button pushed function: UploadHologramButtonGenerate
        function UploadHologramButtonGeneratePushedGenerate(app, event)
            f_sg_gh_upload_holo(app);
        end

        % Button pushed function: ViewHologramButtonMultiplane
        function ViewHologramButtonPushedMultiplane(app, event)
            f_sg_xyz_button_view(app, 'custom', 'phase')
        end

        % Button pushed function: UploadHologramButton
        function UploadHologramButtonPushedMultiplane(app, event)
            f_sg_xyz_button_upload(app, 'custom')
        end

        % Cell selection callback: UIImagePhaseTable
        function UIImagePhaseTableCellSelection(app, event)
            app.UIImagePhaseTableSelection = event.Indices;
        end

        % Button pushed function: AddtohologramButton
        function AddtohologramButtonPushed(app, event)
            f_sg_xyz_button_add_to_holo(app);
        end

        % Button pushed function: ViewselectedphaseButton
        function ViewselectedphaseButtonPushed(app, event)
            f_sg_xyz_button_view(app, 'table_selection', 'phase')
        end

        % Button pushed function: ViewselectedphasefftButton
        function ViewselectedphasefftButtonPushed(app, event)
            f_sg_xyz_button_view(app, 'table_selection', 'fft')
        end

        % Button pushed function: UploadselectedphaseButton
        function UploadselectedphaseButtonPushed(app, event)
            f_sg_xyz_button_upload(app, 'table_selection')
        end

        % Button pushed function: RemoveselectedButton
        function RemoveselectedButtonPushed(app, event)
            f_sg_xyz_button_remove_selected(app);
        end

        % Button pushed function: ViewselectedpatternButton
        function ViewselectedpatternButtonPushed(app, event)
            f_sg_xyz_button_view(app, 'pattern', 'phase')
        end

        % Button pushed function: ViewselectedpatternfftButton
        function ViewselectedpatternfftButtonPushed(app, event)
            f_sg_xyz_button_view(app, 'pattern', 'fft')
        end

        % Button pushed function: UploadselectedpatternButton
        function UploadselectedpatternButtonPushed(app, event)
            f_sg_xyz_button_upload(app, 'pattern')
        end

        % Value changed function: InitializeimagingButton
        function InitializeimagingButtonValueChanged(app, event)
            f_sg_scan_initialize_imaging(app);
        end

        % Callback function
        function ScanZernikeButtonPushed(app, event)
            f_sg_AO_scan_zernike(app);
        end

        % Button pushed function: FillmodestableButton
        function FillmodestableButtonPushed(app, event)
            f_sg_AO_fill_modes_table(app);
        end

        % Value changed function: ScanspermodeEditField
        function ScanspermodeEditFieldValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Callback function
        function ScansperVolZEditFieldValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Button pushed function: PlotHologramButton
        function PlotHologramButtonPushed(app, event)
            [phase, n, m] = f_sg_AO_gen_test_hologram(app);
            f_sg_plot_phase_figure(phase, sprintf('Zernike Hologram %d, w=%d; Znm n=%d; m=%d',app.ModeindexEditField.Value,app.weightEditField.Value, n, m));
        end

        % Button pushed function: UploadHologramButton_2
        function UploadHologramButton_2Pushed(app, event)
            phase = f_sg_AO_gen_test_hologram(app);
            app.SLM_image_pointer.Value = f_sg_im_to_pointer(phase);
            f_SLM_update(app.SLM_ops, app.SLM_image_pointer);
        end

        % Value changed function: ScanZernikeButton
        function ScanZernikeButtonValueChanged(app, event)
            f_sg_AO_scan_zernike(app);
        end

        % Cell edit callback: ZernikeListTable
        function ZernikeListTableCellEdit(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Callback function
        function ViewAOphaseButtonPushed(app, event)
            f_sg_view_hologram_phase(app, app.SLM_AO_Image);
        end

        % Value changed function: RunLUTcalibrationButton
        function RunLUTcalibrationButtonValueChanged(app, event)
            f_sg_LUT_run_calibration(app);
        end

        % Value changed function: BitDepthEditField
        function BitDepthEditFieldValueChanged(app, event)
            f_sg_LUT_update_total_frames(app);
        end

        % Value changed function: NumRegionsEditField
        function NumRegionsEditFieldValueChanged(app, event)
            f_sg_LUT_update_total_frames(app);
        end

        % Value changed function: LUTcorrectionDropDown
        function LUTcorrectionDropDownValueChanged(app, event)
            f_sg_reg_save(app);
        end

        % Value changed function: SelectRegionDropDown
        function SelectRegionDropDownValueChanged(app, event)
            f_sg_reg_update(app);
        end

        % Button pushed function: SaveregButton
        function SaveregButtonPushed(app, event)
            f_sg_reg_save(app);
        end

        % Button pushed function: AddregButton
        function AddregButtonPushed(app, event)
            f_sg_reg_add(app);
        end

        % Button pushed function: DeleteregButton
        function DeleteregButtonPushed(app, event)
            f_sg_reg_delete(app);
        end

        % Button pushed function: SavegroupButton
        function SavegroupButtonPushed(app, event)
            f_sg_pat_save(app);
        end

        % Value changed function: PatterngroupDropDown
        function PatterngroupDropDownValueChanged(app, event)
            f_sg_pat_update(app);
        end

        % Button pushed function: DeletegroupButton
        function DeletegroupButtonPushed(app, event)
            f_sg_pat_delete(app);
        end

        % Button pushed function: AddgroupButton
        function AddgroupButtonPushed(app, event)
            f_sg_pat_add(app);
        end

        % Callback function
        function GroupRegionDropDownValueChanged(app, event)
            f_sg_xyz_pat_update_reg(app);
        end

        % Button pushed function: LoadopsButton
        function LoadopsButtonPushed(app, event)
            f_sg_ops_load(app);
        end

        % Button pushed function: SaveopsButton
        function SaveopsButtonPushed(app, event)
            f_sg_ops_save(app);
        end

        % Value changed function: LUTDropDown
        function LUTDropDownValueChanged(app, event)
            f_sg_lut_update_list(app);
        end

        % Value changed function: AOcorrectionDropDown
        function AOcorrectionDropDownValueChanged(app, event)
            f_sg_reg_save(app);
        end

        % Button pushed function: ViewcurrentphaseButton
        function ViewcurrentphaseButtonPushed(app, event)
            f_sg_view_current_holo(app);
        end

        % Button pushed function: ViewcurrentAOButton
        function ViewcurrentAOButtonPushed(app, event)
            f_sg_AO_plot_current_correction(app);
        end

        % Button pushed function: ViewcurrentlutButton
        function ViewcurrentlutButtonPushed(app, event)
            f_sg_view_current_lut(app);
        end

        % Button pushed function: LoadcalibrationsButton
        function LoadcalibrationsButtonPushed(app, event)
            f_sg_load_calibration(app);
        end

        % Callback function
        function StartoptimizationscanButtonPushed(app, event)
            f_sg_AO_scan_optimization(app);
        end

        % Value changed function: NumiterationsSpinner
        function NumiterationsSpinnerValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Button pushed function: LateralcalibrationButton
        function LateralcalibrationButtonPushed(app, event)
            sg_lateral_calibration(app);
        end

        % Button pushed function: patterneditorButton
        function patterneditorButtonPushed(app, event)
            sg_pattern_plot(app);
        end

        % Button pushed function: SavecurrentlutButton
        function SavecurrentlutButtonPushed(app, event)
            f_sg_save_current_holo(app);
        end

        % Button pushed function: BrowseimageButton
        function BrowseimageButtonPushed(app, event)
            f_sg_gh_custom_phase_browse(app);
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            f_sg_gh_custom_phase_load(app);
        end

        % Button pushed function: SaveXYZmatButton
        function SaveXYZmatButtonPushed(app, event)
            f_sg_save_current_XYZ(app);
        end

        % Value changed function: SLMtypeDropDown
        function SLMtypeDropDownValueChanged(app, event)
            f_sg_params_SLM_type(app);
        end

        % Value changed function: ObjectiveDropDown
        function ObjectiveDropDownValueChanged(app, event)
            f_sg_update_params_obj(app);
        end

        % Button pushed function: PlotexamplestimtraceButton
        function PlotexamplestimtraceButtonPushed(app, event)
            f_sg_plot_stim_pattern_trace(app);
        end

        % Button pushed function: PlotpwcorrectionButton
        function PlotpwcorrectionButtonPushed(app, event)
            f_sg_pw_plot_correction(app);
        end

        % Button pushed function: OptimizeW_estwithinpatButton
        function OptimizeW_estwithinpatButtonPushed(app, event)
            f_sg_pw_correct_weights_within_pat(app);
        end

        % Button pushed function: OptimizeW_estacrosspatButton
        function OptimizeW_estacrosspatButtonPushed(app, event)
            f_sg_pw_correct_weights_across_pat(app);
        end

        % Cell edit callback: UIImagePhaseTable
        function UIImagePhaseTableCellEdit(app, event)
            f_sg_tab_val_changed(app, event);
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            f_sg_clear_table(app);
        end

        % Button pushed function: EstimateIntensityButton
        function EstimateIntensityButtonPushed(app, event)
            f_sg_update_table_intensity(app);
        end

        % Button pushed function: CloseallfigsButton
        function CloseallfigsButtonPushed(app, event)
            f_sg_close_all_figs();
        end

        % Callback function
        function StartoptimizationscanbymodeButtonPushed(app, event)
            f_sg_AO_scan_optimization_bymode(app);
        end

        % Callback function
        function GSfromimageButtonPushed(app, event)
            f_sg_GS_from_image(app);
        end

        % Button pushed function: FitAOcorretionsButton
        function FitAOcorretionsButtonPushed(app, event)
            f_sg_AO_fit_corrections(app);
        end

        % Value changed function: StartoptimizationButton
        function StartoptimizationButtonValueChanged(app, event)
            f_sg_AO_start_optimization(app);
        end

        % Button pushed function: ViewcurrentimageButton
        function ViewcurrentimageButtonPushed(app, event)
            f_sg_view_current_image(app);
        end

        % Callback function
        function ShowPWcorrectedI_targButtonValueChanged(app, event)
            f_sg_apply_PW(app);
        end

        % Button pushed function: UpdateW_estButton
        function UpdateW_estButtonPushed(app, event)
            f_sg_update_W_est(app);
        end

        % Value changed function: OutputSLMexternaltriggerCheckBox
        function OutputSLMexternaltriggerCheckBoxValueChanged(app, event)
            f_sg_set_slm_out_trig(app);
        end

        % Button pushed function: LoadXYZmatButton
        function LoadXYZmatButtonPushed(app, event)
            f_sg_load_xyz_patterns(app);
        end

        % Button pushed function: ImportROIsButton
        function ImportROIsButtonPushed(app, event)
            disp("Load ROIs");
        end

        % Button pushed function: ImportTableButton
        function ImportTableButtonPushed(app, event)
           f_sg_import_tables(app);
        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            f_sg_export_tables(app);
        end

        % Value changed function: ZoomEditField
        function ZoomEditFieldValueChanged(app, event)
            value = app.ZoomEditField.Value;
            app.SLM_ops.zoom = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.SLMregioneditorPanel = uipanel(app.ParametersTab);
            app.SLMregioneditorPanel.Title = 'SLM region editor';
            app.BeamdumpYEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpYEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamdumpXEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpXEditField.Tooltip = {'baseline X offset of zero coord'};
            app.ZOsuppWEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZOsuppphaseEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.SelectregionDropDownLabel = uilabel(app.SLMregioneditorPanel);
            app.SelectregionDropDownLabel.Text = 'Select region';
            app.RegionnameEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.RegionnameEditFieldLabel.Text = 'Region name';
            app.WavelengthnmEditField_2Label = uilabel(app.SLMregioneditorPanel);
            app.WavelengthnmEditField_2Label.Text = 'Wavelength (nm)';
            app.LUTcorrectionLabel = uilabel(app.SLMregioneditorPanel);
            app.LUTcorrectionLabel.Text = 'LUT correction';
            app.minEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.minEditFieldLabel.Text = 'min';
            app.maxEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.minEditField_2Label = uilabel(app.SLMregioneditorPanel);
            app.minEditField_2Label.Text = 'min';
            app.maxEditField_2Label = uilabel(app.SLMregioneditorPanel);
            app.maxEditField_2Label.Text = 'max';
            app.XYZaffinetransformLabel = uilabel(app.SLMregioneditorPanel);
            app.XYZaffinetransformLabel.Text = 'XYZ affine transform';
            app.AOcorrectionDropDownLabel = uilabel(app.SLMregioneditorPanel);
            app.AOcorrectionDropDownLabel.Text = 'AO correction';
            app.EffectiveNALabel = uilabel(app.SLMregioneditorPanel);
            app.EffectiveNALabel.Text = 'Effective NA';
            app.BeamdiameterpixLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamdiameterpixLabel.Text = 'Beam diameter (pix)';
            app.XoffsetEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.XoffsetEditFieldLabel.Text = 'X offset';
            app.YoffsetEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.YoffsetEditFieldLabel.Text = 'Y offset';
            app.ZoffsetEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.ZoffsetEditFieldLabel.Text = 'Z offset';
            app.XZcorrEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.XZcorrEditFieldLabel.Text = 'X/Z corr';
            app.YZcorrEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.YZcorrEditFieldLabel.Text = 'Y/Z corr';
            app.YZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YZcorrEditField.Tooltip = {'axial Y/Z offset correction'};
            app.XZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XZcorrEditField.Tooltip = {'axial X/Z offset correction'};
            app.ZoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZoffsetEditField.Tooltip = {'baseline Z offset of zero coord'};
            app.YoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YoffsetEditField.Tooltip = {'baseline Y offset of zero coord'};
            app.XoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XoffsetEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamxyzandaxialcorrectionsLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamxyzandaxialcorrectionsLabel.Text = 'Beam xyz and axial corrections';
            app.PointweightcorrectionDropDownLabel = uilabel(app.SLMregioneditorPanel);
            app.PointweightcorrectionDropDownLabel.Text = 'Point weight correction';
            app.PointweightcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.PointweightcorrectionDropDown.Items = {};
            app.PointweightcorrectionDropDown.Value = {};
            app.AOcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.AOcorrectionDropDown.Items = {};
            app.AOcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AOcorrectionDropDownValueChanged, true);
            app.AOcorrectionDropDown.Value = {};
            app.XYZaffinetransformDropDown = uidropdown(app.SLMregioneditorPanel);
            app.XYZaffinetransformDropDown.Items = {};
            app.XYZaffinetransformDropDown.Value = {};
            app.LUTcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.LUTcorrectionDropDown.Items = {};
            app.LUTcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTcorrectionDropDownValueChanged, true);
            app.LUTcorrectionDropDown.Value = {};
            app.regionBeamDiameterEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionZerooutsidephasediameterCheckBox = uicheckbox(app.SLMregioneditorPanel);
            app.regionZerooutsidephasediameterCheckBox.Text = 'Zero outside phase diameter';
            app.regionZerooutsidephasediameterCheckBox.Value = true;
            app.BeamdumpXEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamdumpXEditFieldLabel.Text = 'Beam dump X';
            app.BeamdumpYEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamdumpYEditFieldLabel.Text = 'Beam dump Y';
            app.PhasediameterpixLabel = uilabel(app.SLMregioneditorPanel);
            app.PhasediameterpixLabel.Text = 'Phase diameter (pix)';
            app.regionPhaseDiameterEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionEffectiveNAEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionEffectiveNAEditField.Tooltip = {'Effective Numerical Aperture (NA) is used for defocusing. It is an estimate of how much of the Back Focal Plane (BFP) the SLM occupies [NAeff = NA * (size_SLM / diameter_BFP)]. Size_SLM refers to the size of the unit circle projected onto the BFP used for Defocusing.'};
            app.regionWavelengthnmEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthLabel = uilabel(app.SLMregioneditorPanel);
            app.regionwidthLabel.Text = 'Region width [0, 1]';
            app.regionheightmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightLabel = uilabel(app.SLMregioneditorPanel);
            app.regionheightLabel.Text = 'Region height [0,1]';
            app.RegionnameEditField = uieditfield(app.SLMregioneditorPanel, 'text');
            app.RegionnameEditField.Value = ' ';
            app.DeleteregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.DeleteregButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteregButtonPushed, true);
            app.DeleteregButton.Text = 'Delete reg';
            app.AddregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.AddregButton.ButtonPushedFcn = createCallbackFcn(app, @AddregButtonPushed, true);
            app.AddregButton.Text = 'Add reg';
            app.SaveregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.SaveregButton.ButtonPushedFcn = createCallbackFcn(app, @SaveregButtonPushed, true);
            app.SaveregButton.Text = 'Save reg';
            app.ZOsuppphaseEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.ZOsuppphaseEditFieldLabel.Text = 'ZO supp phase';
            app.ZOsuppWEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.ZOsuppWEditFieldLabel.Text = 'ZO supp W';
            app.SelectRegionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.SelectRegionDropDown.Items = {};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = {};
            app.SaveopsButton = uibutton(app.ParametersTab, 'push');
            app.SaveopsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveopsButtonPushed, true);
            app.SaveopsButton.Text = 'Save ops';
            app.LoadopsButton = uibutton(app.ParametersTab, 'push');
            app.LoadopsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadopsButtonPushed, true);
            app.LoadopsButton.Text = 'Load ops';
            app.LoadcalibrationsButton = uibutton(app.ParametersTab, 'push');
            app.LoadcalibrationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcalibrationsButtonPushed, true);
            app.LoadcalibrationsButton.Text = 'Load calibrations';
            app.AOcorrZtoleranceEditField = uieditfield(app.ParametersTab, 'numeric');
            app.AOcorrZtoleranceEditField.Value = 21;
            app.SLMwidthEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMwidthEditFieldLabel.Text = 'SLM width';
            app.SLMheightEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMheightEditFieldLabel.Text = 'SLM height';
            app.ObjectiveRIEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveRIEditFieldLabel.Text = 'Objective RI';
            app.NIDAQdeviceEditFieldLabel = uilabel(app.ParametersTab);
            app.NIDAQdeviceEditFieldLabel.Text = 'NI DAQ device';
            app.DAQcounterchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQcounterchannelEditFieldLabel.Text = 'DAQ counter channel';
            app.DAQAIchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQAIchannelEditFieldLabel.Text = 'DAQ AI channel';
            app.LUTDropDownLabel = uilabel(app.ParametersTab);
            app.LUTDropDownLabel.Text = 'LUT';
            app.xpixelsEditFieldLabel = uilabel(app.ParametersTab);
            app.xpixelsEditFieldLabel.Text = 'x pixels';
            app.ypixelsEditFieldLabel = uilabel(app.ParametersTab);
            app.ypixelsEditFieldLabel.Text = 'y pixels';
            app.ypixelsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ypixelsEditField.Limits = [2 4096];
            app.ypixelsEditField.RoundFractionalValues = 'on';
            app.ypixelsEditField.Value = 256;
            app.xpixelsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.xpixelsEditField.Limits = [2 4096];
            app.xpixelsEditField.RoundFractionalValues = 'on';
            app.xpixelsEditField.Value = 256;
            app.ZoomEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ZoomEditField.Limits = [0.1 100];
            app.ZoomEditField.ValueChangedFcn = createCallbackFcn(app, @ZoomEditFieldValueChanged, true);
            app.ZoomEditField.Value = 1;
            app.FOVsizeumEditField = uieditfield(app.ParametersTab, 'numeric');
            app.FOVsizeumEditField.Limits = [1 Inf];
            app.FOVsizeumEditField.Value = 500;
            app.ZoomEditFieldLabel = uilabel(app.ParametersTab);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.DAQAOchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQAOchannelEditFieldLabel.Text = 'DAQ AO channel';
            app.AOcorrZtoleranceEditFieldLabel = uilabel(app.ParametersTab);
            app.AOcorrZtoleranceEditFieldLabel.Text = 'AO corr Z tolerance';
            app.FOVsizeumEditFieldLabel = uilabel(app.ParametersTab);
            app.FOVsizeumEditFieldLabel.Text = 'FOV size (um)';
            app.ObjectiveDropDown = uidropdown(app.ParametersTab);
            app.ObjectiveDropDown.Items = {};
            app.ObjectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDownValueChanged, true);
            app.ObjectiveDropDown.Value = {};
            app.DAQAOchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQAOchannelEditField.Limits = [0 1];
            app.DAQAOchannelEditField.RoundFractionalValues = 'on';
            app.DAQAIchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQAIchannelEditField.Limits = [0 1];
            app.DAQAIchannelEditField.RoundFractionalValues = 'on';
            app.DAQcounterchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQcounterchannelEditField.Limits = [0 1];
            app.DAQcounterchannelEditField.RoundFractionalValues = 'on';
            app.NIDAQdeviceEditField = uieditfield(app.ParametersTab, 'text');
            app.SLMtypeDropDownLabel = uilabel(app.ParametersTab);
            app.SLMtypeDropDownLabel.Text = 'SLM type';
            app.ObjectiveDropDownLabel = uilabel(app.ParametersTab);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.TubelengthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.TubelengthEditField.Value = 0.18;
            app.ObjectiveRIEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveRIEditField.Value = 1.33;
            app.TubelengthEditFieldLabel = uilabel(app.ParametersTab);
            app.TubelengthEditFieldLabel.Text = 'Tube length';
            app.SLMwidthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMwidthEditField.Editable = 'off';
            app.SLMheightEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMheightEditField.Editable = 'off';
            app.LUTDropDown = uidropdown(app.ParametersTab);
            app.LUTDropDown.Items = {};
            app.LUTDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTDropDownValueChanged, true);
            app.LUTDropDown.Tooltip = {'For 1920x1152 model bot global .lut and regional .txt files are treated the same and can be upload with lut update functiond. For the old 512x512 with overdrive regional .txt needs to be uploaded during SDK creation '};
            app.LUTDropDown.Value = {};
            app.SLMtypeDropDown = uidropdown(app.ParametersTab);
            app.SLMtypeDropDown.Items = {};
            app.SLMtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SLMtypeDropDownValueChanged, true);
            app.SLMtypeDropDown.Value = {};
            app.OutputSLMexternaltriggerCheckBox = uicheckbox(app.ParametersTab);
            app.OutputSLMexternaltriggerCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputSLMexternaltriggerCheckBoxValueChanged, true);
            app.OutputSLMexternaltriggerCheckBox.Text = 'Output SLM external trigger';
            app.XYZpatternsTab = uitab(app.TabGroup);
            app.XYZpatternsTab.Title = 'XYZ patterns';
            app.UIImagePhaseTable = uitable(app.XYZpatternsTab);
            app.UIImagePhaseTable.ColumnName = '';
            app.UIImagePhaseTable.RowName = {};
            app.UIImagePhaseTable.ColumnSortable = true;
            app.UIImagePhaseTable.ColumnEditable = [true true true true true true false];
            app.UIImagePhaseTable.CellEditCallback = createCallbackFcn(app, @UIImagePhaseTableCellEdit, true);
            app.UIImagePhaseTable.CellSelectionCallback = createCallbackFcn(app, @UIImagePhaseTableCellSelection, true);
            app.UIImagePhaseTable.Tooltip = {'I_targ - Desired relative intensity within pattern, or across patterns when optimization is used.'; ''; 'I_est - estimated intensity of points based on diffractioin efficiency, which decreases with lateral distance from (0,0). Additionally, If PW correction is on, this SLM efficiency is also incorporated into the intensity estimate. If "I_est = I^2" is cheked, this intensity is squared.'; ''; 'W_est - estimated weights to be used in superposition algorithm for each indifidual point phase, while caclulating the total wavefront. The relative weights should be approximately the square root of relative intensity w = sqrt(I).'};
            app.SaveXYZmatButton = uibutton(app.XYZpatternsTab, 'push');
            app.SaveXYZmatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveXYZmatButtonPushed, true);
            app.SaveXYZmatButton.Text = 'Save XYZ mat';
            app.patterneditorButton = uibutton(app.XYZpatternsTab, 'push');
            app.patterneditorButton.ButtonPushedFcn = createCallbackFcn(app, @patterneditorButtonPushed, true);
            app.patterneditorButton.Text = 'pattern editor';
            app.OptimizeW_estacrosspatButton = uibutton(app.XYZpatternsTab, 'push');
            app.OptimizeW_estacrosspatButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeW_estacrosspatButtonPushed, true);
            app.OptimizeW_estacrosspatButton.Text = 'Optimize W_est across pat';
            app.pointsizeumEditField = uieditfield(app.XYZpatternsTab, 'numeric');
            app.pointsizeumEditField.Limits = [1 Inf];
            app.pointsizeumEditField.Value = 3;
            app.OptimizeW_estwithinpatButton = uibutton(app.XYZpatternsTab, 'push');
            app.OptimizeW_estwithinpatButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeW_estwithinpatButtonPushed, true);
            app.OptimizeW_estwithinpatButton.Tooltip = {'Optimizes weights within a single pattern and for each point phase to make the output pattern have relative I_est intensities proportional to the relative I_targ intensities'};
            app.OptimizeW_estwithinpatButton.Text = 'Optimize W_est within pat';
            app.EstimateIntensityButton = uibutton(app.XYZpatternsTab, 'push');
            app.EstimateIntensityButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateIntensityButtonPushed, true);
            app.EstimateIntensityButton.Text = 'Estimate Intensity';
            app.ViewpredictedPSFButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewpredictedPSFButton.Text = 'View predicted PSF';
            app.fftzoomed20xCheckBox = uicheckbox(app.XYZpatternsTab);
            app.fftzoomed20xCheckBox.Text = 'fft zoomed 20x';
            app.fftzoomed20xCheckBox.Value = true;
            app.fftampsquaredCheckBox = uicheckbox(app.XYZpatternsTab);
            app.fftampsquaredCheckBox.Text = 'fft amp squared';
            app.fftampsquaredCheckBox.Value = true;
            app.fftdefocusumEditField = uieditfield(app.XYZpatternsTab, 'numeric');
            app.ViewselectedpatternfftButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewselectedpatternfftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedpatternfftButtonPushed, true);
            app.ViewselectedpatternfftButton.Text = 'View selected pattern fft';
            app.ViewselectedpatternButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewselectedpatternButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedpatternButtonPushed, true);
            app.ViewselectedpatternButton.Text = 'View selected pattern';
            app.UploadselectedpatternButton = uibutton(app.XYZpatternsTab, 'push');
            app.UploadselectedpatternButton.ButtonPushedFcn = createCallbackFcn(app, @UploadselectedpatternButtonPushed, true);
            app.UploadselectedpatternButton.Text = 'Upload selected pattern';
            app.ClearallButton = uibutton(app.XYZpatternsTab, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.ViewselectedphasefftButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewselectedphasefftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedphasefftButtonPushed, true);
            app.ViewselectedphasefftButton.Text = 'View selected phase fft';
            app.ViewselectedphaseButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewselectedphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedphaseButtonPushed, true);
            app.ViewselectedphaseButton.Text = 'View selected phase';
            app.RemoveselectedButton = uibutton(app.XYZpatternsTab, 'push');
            app.RemoveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedButtonPushed, true);
            app.RemoveselectedButton.Text = 'Remove selected';
            app.UploadselectedphaseButton = uibutton(app.XYZpatternsTab, 'push');
            app.UploadselectedphaseButton.ButtonPushedFcn = createCallbackFcn(app, @UploadselectedphaseButtonPushed, true);
            app.UploadselectedphaseButton.Text = 'Upload selected phase';
            app.AddtohologramButton = uibutton(app.XYZpatternsTab, 'push');
            app.AddtohologramButton.ButtonPushedFcn = createCallbackFcn(app, @AddtohologramButtonPushed, true);
            app.AddtohologramButton.Text = 'Add to hologram ->';
            app.CreatecustompointhologramPanel = uipanel(app.XYZpatternsTab);
            app.CreatecustompointhologramPanel.Title = 'Create custom point hologram';
            app.UploadHologramButton = uibutton(app.CreatecustompointhologramPanel, 'push');
            app.UploadHologramButton.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonPushedMultiplane, true);
            app.UploadHologramButton.Text = 'Upload Hologram';
            app.ViewHologramButtonMultiplane = uibutton(app.CreatecustompointhologramPanel, 'push');
            app.ViewHologramButtonMultiplane.ButtonPushedFcn = createCallbackFcn(app, @ViewHologramButtonPushedMultiplane, true);
            app.ViewHologramButtonMultiplane.Text = 'View Hologram';
            app.WeightEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.WeightEditField.Value = '1';
            app.PointweightLabel = uilabel(app.CreatecustompointhologramPanel);
            app.PointweightLabel.Text = 'Point weight';
            app.ZoffsetumEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.ZoffsetumEditField.Value = '0';
            app.DefocusLabel = uilabel(app.CreatecustompointhologramPanel);
            app.DefocusLabel.Text = 'Defocus';
            app.PatternnumberEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.PatternnumberEditFieldLabel.Text = 'Pattern number';
            app.XdisplacementEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.XdisplacementEditFieldLabel.Text = 'X displacement';
            app.YdisplacementEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.YdisplacementEditFieldLabel.Text = 'Y displacement';
            app.YdisplacementEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.YdisplacementEditField.Value = '0';
            app.XdisplacementEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.XdisplacementEditField.Value = '0';
            app.XYdisplacementLabel = uilabel(app.CreatecustompointhologramPanel);
            app.XYdisplacementLabel.Text = 'X Y displacement';
            app.ZoffsetumEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.ZoffsetumEditFieldLabel.Text = 'Z offset (um)';
            app.WeightEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.PatternnumberEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.PatternnumberEditField.Value = '1';
            app.IntensitytargetEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.IntensitytargetEditField.Value = '1';
            app.IntensitytargetEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.IntensitytargetEditFieldLabel.Text = 'Intensity target';
            app.SavegroupButton = uibutton(app.XYZpatternsTab, 'push');
            app.SavegroupButton.ButtonPushedFcn = createCallbackFcn(app, @SavegroupButtonPushed, true);
            app.SavegroupButton.Text = 'Save group';
            app.DeletegroupButton = uibutton(app.XYZpatternsTab, 'push');
            app.DeletegroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeletegroupButtonPushed, true);
            app.DeletegroupButton.Text = 'Delete group';
            app.AddgroupButton = uibutton(app.XYZpatternsTab, 'push');
            app.AddgroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddgroupButtonPushed, true);
            app.AddgroupButton.Text = 'Add group';
            app.GroupnameEditField = uieditfield(app.XYZpatternsTab, 'text');
            app.PatterngroupDropDown = uidropdown(app.XYZpatternsTab);
            app.PatterngroupDropDown.Items = {'Multiplane'};
            app.PatterngroupDropDown.ValueChangedFcn = createCallbackFcn(app, @PatterngroupDropDownValueChanged, true);
            app.PatterngroupDropDown.Value = 'Multiplane';
            app.fftdefocusumEditFieldLabel = uilabel(app.XYZpatternsTab);
            app.fftdefocusumEditFieldLabel.Text = 'fft defocus (um)';
            app.PatterngroupDropDownLabel = uilabel(app.XYZpatternsTab);
            app.PatterngroupDropDownLabel.Text = 'Pattern group';
            app.GroupnameEditFieldLabel = uilabel(app.XYZpatternsTab);
            app.GroupnameEditFieldLabel.Text = 'Group name';
            app.pointsizeumEditFieldLabel = uilabel(app.XYZpatternsTab);
            app.pointsizeumEditFieldLabel.Text = 'point size (um)';
            app.XYZpatalgotithmDropDownLabel = uilabel(app.XYZpatternsTab);
            app.XYZpatalgotithmDropDownLabel.Text = 'XYZ pat algotithm';
            app.XYZpatalgotithmDropDown = uidropdown(app.XYZpatternsTab);
            app.XYZpatalgotithmDropDown.Items = {};
            app.XYZpatalgotithmDropDown.Tooltip = {'LW indicates algorighms from Waller Lab doi.org/10.1364/OPTICA.4.001306'};
            app.XYZpatalgotithmDropDown.Value = {};
            app.MakedisksCheckBox = uicheckbox(app.XYZpatternsTab);
            app.MakedisksCheckBox.Text = 'Make disks';
            app.I_targI22PCheckBox = uicheckbox(app.XYZpatternsTab);
            app.I_targI22PCheckBox.Text = 'I_targ = I^2 (2P)';
            app.I_targI22PCheckBox.Value = true;
            app.I_estI22PCheckBox = uicheckbox(app.XYZpatternsTab);
            app.I_estI22PCheckBox.Text = 'I_est  = I^2 (2P)';
            app.PlotestimationCheckBox = uicheckbox(app.XYZpatternsTab);
            app.PlotestimationCheckBox.Text = 'Plot estimation';
            app.PlotwoptimizationCheckBox = uicheckbox(app.XYZpatternsTab);
            app.PlotwoptimizationCheckBox.Text = 'Plot w optimization';
            app.UpdateW_estButton = uibutton(app.XYZpatternsTab, 'push');
            app.UpdateW_estButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateW_estButtonPushed, true);
            app.UpdateW_estButton.Tooltip = {'Using sqrt(1P I_targ) values'};
            app.UpdateW_estButton.Text = 'Update W_est';
            app.LoadXYZmatButton = uibutton(app.XYZpatternsTab, 'push');
            app.LoadXYZmatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadXYZmatButtonPushed, true);
            app.LoadXYZmatButton.Text = 'Load XYZ mat';
            app.ImportTableButton = uibutton(app.XYZpatternsTab, 'push');
            app.ImportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTableButtonPushed, true);
            app.ImportTableButton.Text = {'Import Table'; ''};
            app.ImportROIsButton = uibutton(app.XYZpatternsTab, 'push');
            app.ImportROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportROIsButtonPushed, true);
            app.ImportROIsButton.Enable = 'off';
            app.ImportROIsButton.Visible = 'off';
            app.ImportROIsButton.Text = 'Import ROIs';
            app.ExportTableButton = uibutton(app.XYZpatternsTab, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = {'Export Table'; ''};
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.GroupscanPanel = uipanel(app.ScanTab);
            app.GroupscanPanel.Title = 'Group scan';
            app.ImagingEOFcounterchantriggeredPanel = uipanel(app.GroupscanPanel);
            app.ImagingEOFcounterchantriggeredPanel.Title = 'Imaging (EOF counter chan triggered)';
            app.PlotSLMupdateratesCheckBox = uicheckbox(app.ImagingEOFcounterchantriggeredPanel);
            app.PlotSLMupdateratesCheckBox.Text = 'Plot SLM update rates';
            app.PlotSLMupdateratesCheckBox.Value = true;
            app.ImagingReadyLamp = uilamp(app.ImagingEOFcounterchantriggeredPanel);
            app.InitializeimagingButton = uibutton(app.ImagingEOFcounterchantriggeredPanel, 'state');
            app.InitializeimagingButton.ValueChangedFcn = createCallbackFcn(app, @InitializeimagingButtonValueChanged, true);
            app.InitializeimagingButton.Text = 'Initialize imaging';
            app.NumVolumesEditField = uieditfield(app.ImagingEOFcounterchantriggeredPanel, 'numeric');
            app.NumVolumesEditField.Limits = [1 Inf];
            app.NumVolumesEditField.Value = 1;
            app.PatterngroupLabel = uilabel(app.ImagingEOFcounterchantriggeredPanel);
            app.PatterngroupLabel.Text = 'Pattern group';
            app.PatternDropDownCtr = uidropdown(app.ImagingEOFcounterchantriggeredPanel);
            app.PatternDropDownCtr.Items = {};
            app.PatternDropDownCtr.Tooltip = {'When stim pattern is not used, the default uploaded pattern on stim side stays on'};
            app.PatternDropDownCtr.Value = {};
            app.NumVolumesEditFieldLabel = uilabel(app.ImagingEOFcounterchantriggeredPanel);
            app.NumVolumesEditFieldLabel.Text = 'Num Volumes';
            app.TriggertypeDropDownLabel = uilabel(app.ImagingEOFcounterchantriggeredPanel);
            app.TriggertypeDropDownLabel.Text = 'Trigger type';
            app.TriggertypeDropDown = uidropdown(app.ImagingEOFcounterchantriggeredPanel);
            app.TriggertypeDropDown.Items = {};
            app.TriggertypeDropDown.Value = {};
            app.StimulationAIinputVpatternnumPanel = uipanel(app.GroupscanPanel);
            app.StimulationAIinputVpatternnumPanel.Tooltip = {'AI 0=off'; '[n_goup * 5V / num_group] = group voltage id'};
            app.StimulationAIinputVpatternnumPanel.Title = 'Stimulation (AI input V = pattern num)';
            app.PatterngroupLabel_2 = uilabel(app.StimulationAIinputVpatternnumPanel);
            app.PatterngroupLabel_2.Text = 'Pattern group';
            app.PatternDropDownAI = uidropdown(app.StimulationAIinputVpatternnumPanel);
            app.PatternDropDownAI.Items = {};
            app.PatternDropDownAI.Tooltip = {'AI 0=off'; '[n_goup * 5V / num_group] = group voltage id'};
            app.PatternDropDownAI.Value = {};
            app.stimcontrolsourceButtonGroup = uibuttongroup(app.StimulationAIinputVpatternnumPanel);
            app.stimcontrolsourceButtonGroup.Title = 'stim control source';
            app.CustomstimButton = uitogglebutton(app.stimcontrolsourceButtonGroup);
            app.CustomstimButton.Text = 'Custom stim';
            app.AIinputButton = uitogglebutton(app.stimcontrolsourceButtonGroup);
            app.AIinputButton.Text = 'AI input';
            app.AIinputButton.Value = true;
            app.CustomstimprotocolPanel = uipanel(app.GroupscanPanel);
            app.CustomstimprotocolPanel.Title = 'Custom stim protocol';
            app.patternrepeatsEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.patternrepeatsEditFieldLabel.Text = 'pattern repeats';
            app.patternorderDropDownLabel = uilabel(app.CustomstimprotocolPanel);
            app.patternorderDropDownLabel.Text = 'pattern order';
            app.patterndurationEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.patterndurationEditFieldLabel.Text = 'pattern duration';
            app.patternintervalEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.patternintervalEditFieldLabel.Text = 'pattern interval';
            app.patternintervaljitterEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.patternintervaljitterEditFieldLabel.Text = 'pattern interval jitter';
            app.patternintervaljitterEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.patternintervaljitterEditField.Value = 1;
            app.patternrepeatsEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.patternrepeatsEditField.Value = 5;
            app.patternintervalEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.patternintervalEditField.Value = 2;
            app.patterndurationEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.patterndurationEditField.Value = 1;
            app.initialdelayEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.initialdelayEditFieldLabel.Text = 'initial delay';
            app.initialdelayEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.initialdelayEditField.Value = 30;
            app.PlotexamplestimtraceButton = uibutton(app.CustomstimprotocolPanel, 'push');
            app.PlotexamplestimtraceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotexamplestimtraceButtonPushed, true);
            app.PlotexamplestimtraceButton.Text = 'Plot example stim trace';
            app.patternorderDropDown = uidropdown(app.CustomstimprotocolPanel);
            app.patternorderDropDown.Items = {'Random', 'Sequence'};
            app.patternorderDropDown.Value = 'Random';
            app.GenerateHologramTab = uitab(app.TabGroup);
            app.GenerateHologramTab.Title = 'Generate Hologram';
            app.TabGroup2 = uitabgroup(app.GenerateHologramTab);
            app.PistonTab = uitab(app.TabGroup2);
            app.PistonTab.Title = 'Piston';
            app.GeneratepistonButton = uibutton(app.PistonTab, 'push');
            app.GeneratepistonButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlankLensButtonPushed, true);
            app.GeneratepistonButton.Text = 'Generate piston';
            app.PixelValueLabel = uilabel(app.PistonTab);
            app.PixelValueLabel.Text = 'Pixel Value';
            app.BlankPixelValueEditField = uieditfield(app.PistonTab, 'numeric');
            app.BlazedGratingTab = uitab(app.TabGroup2);
            app.BlazedGratingTab.Title = 'Blazed Grating';
            app.GenerateBlazedGratingButton = uibutton(app.BlazedGratingTab, 'push');
            app.GenerateBlazedGratingButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlazedGratingButtonPushed, true);
            app.GenerateBlazedGratingButton.Text = 'Generate Blazed';
            app.PeriodEditFieldLabel = uilabel(app.BlazedGratingTab);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.BlazReverseCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazReverseCheckBox.Text = 'Reverse direction';
            app.BlazHorizontalCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazHorizontalCheckBox.Text = 'Horizontal';
            app.BlazIncreasingCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazIncreasingCheckBox.Text = 'Increasing';
            app.BlazIncreasingCheckBox.Value = true;
            app.BlazPeriodEditField = uieditfield(app.BlazedGratingTab, 'numeric');
            app.BlazPeriodEditField.Value = 127;
            app.StripesTab = uitab(app.TabGroup2);
            app.StripesTab.Title = 'Stripes';
            app.GenerateStripesButton = uibutton(app.StripesTab, 'push');
            app.GenerateStripesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStripesButtonPushed, true);
            app.GenerateStripesButton.Text = 'Generate Stripes';
            app.PixelsPerStripeLabel = uilabel(app.StripesTab);
            app.PixelsPerStripeLabel.Text = 'Pixels Per Stripe';
            app.HorizontalstripeCheckBox = uicheckbox(app.StripesTab);
            app.HorizontalstripeCheckBox.Text = 'Horizontal stripe';
            app.StripePixelPerStripeEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripePixelPerStripeEditField.Value = 8;
            app.PixelValueEditFieldLabel = uilabel(app.StripesTab);
            app.PixelValueEditFieldLabel.Text = 'Pixel Value';
            app.GrayEditFieldLabel = uilabel(app.StripesTab);
            app.GrayEditFieldLabel.Text = 'Gray';
            app.StripeGrayEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripeGrayEditField.Value = 127;
            app.StripePixelValueEditField = uieditfield(app.StripesTab, 'numeric');
            app.DefocusTab = uitab(app.TabGroup2);
            app.DefocusTab.Title = 'Defocus';
            app.GeneratedefocusButton = uibutton(app.DefocusTab, 'push');
            app.GeneratedefocusButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratedefocusButtonPushed, true);
            app.GeneratedefocusButton.Text = 'Generate defocus';
            app.DefocusdistumLabel = uilabel(app.DefocusTab);
            app.DefocusdistumLabel.Text = 'Defocus dist (um)';
            app.DeficusWeightEditField = uieditfield(app.DefocusTab, 'numeric');
            app.CrossTab = uitab(app.TabGroup2);
            app.CrossTab.Title = 'Cross';
            app.GenerateCrossButton = uibutton(app.CrossTab, 'push');
            app.GenerateCrossButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCrossButtonPushed, true);
            app.GenerateCrossButton.Text = 'Generate cross';
            app.PixelValueEditFieldLabel_2 = uilabel(app.CrossTab);
            app.PixelValueEditFieldLabel_2.Text = 'Pixel Value';
            app.GrayEditFieldLabel_2 = uilabel(app.CrossTab);
            app.GrayEditFieldLabel_2.Text = 'Gray';
            app.AngleradLabel = uilabel(app.CrossTab);
            app.AngleradLabel.Text = 'Angle (rad)';
            app.CrossAngleEditField = uieditfield(app.CrossTab, 'numeric');
            app.CrossAngleEditField.Value = 0.7854;
            app.CrossGrayEditField = uieditfield(app.CrossTab, 'numeric');
            app.CrossGrayEditField.Value = 127;
            app.CrossPixelValueEditField = uieditfield(app.CrossTab, 'numeric');
            app.FresnelLensTab = uitab(app.TabGroup2);
            app.FresnelLensTab.Title = 'Fresnel Lens';
            app.GenerateFresnelLensButton = uibutton(app.FresnelLensTab, 'push');
            app.GenerateFresnelLensButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFresnelLensButtonPushed, true);
            app.GenerateFresnelLensButton.Text = 'Generate Fresnel';
            app.CenterXEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterXEditFieldLabel.Text = 'CenterX';
            app.CenterYEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterYEditFieldLabel.Text = 'CenterY';
            app.RadiusEditFieldLabel = uilabel(app.FresnelLensTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.PowerEditFieldLabel = uilabel(app.FresnelLensTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.FresHorizontalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresHorizontalCheckBox.Text = 'Horizontal';
            app.FresCylindricalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresCylindricalCheckBox.Text = 'Cylindrical';
            app.FresCylindricalCheckBox.Value = true;
            app.FresPowerEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresPowerEditField.Value = 1;
            app.FresRadiusEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresRadiusEditField.Value = 576;
            app.FresCenterYEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterYEditField.Value = 576;
            app.FresCenterXEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterXEditField.Value = 960;
            app.ZernikeTab = uitab(app.TabGroup2);
            app.ZernikeTab.Title = 'Zernike';
            app.GenerateZernikeButton = uibutton(app.ZernikeTab, 'push');
            app.GenerateZernikeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateZernikeButtonPushed, true);
            app.GenerateZernikeButton.Text = 'Generate Zernike';
            app.CenterXEditField_2Label = uilabel(app.ZernikeTab);
            app.CenterXEditField_2Label.Text = 'CenterX';
            app.CenterYEditField_5Label = uilabel(app.ZernikeTab);
            app.CenterYEditField_5Label.Text = 'CenterY';
            app.RadiusEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.RadiusEditFieldLabel_2.Text = 'Radius';
            app.PistonEditFieldLabel = uilabel(app.ZernikeTab);
            app.PistonEditFieldLabel.Text = 'Piston';
            app.TiltXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltXEditFieldLabel.Text = 'TiltX';
            app.TiltYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltYEditFieldLabel.Text = 'TiltY';
            app.PowerEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.PowerEditFieldLabel_2.Text = 'Power';
            app.AstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigXEditFieldLabel.Text = 'AstigX';
            app.AstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigYEditFieldLabel.Text = 'AstigY';
            app.ComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaXEditFieldLabel.Text = 'ComaX';
            app.ComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaYEditFieldLabel.Text = 'ComaY';
            app.PrimarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.PrimarySphericalEditFieldLabel.Text = 'PrimarySpherical';
            app.TrefoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilXEditFieldLabel.Text = 'TrefoilX';
            app.TrefoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilYEditFieldLabel.Text = 'TrefoilY';
            app.SecondaryAstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigXEditFieldLabel.Text = 'SecondaryAstigX';
            app.SecondaryAstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigYEditFieldLabel.Text = 'SecondaryAstigY';
            app.SecondaryComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaXEditFieldLabel.Text = 'SecondaryComaX';
            app.SecondaryComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaYEditFieldLabel.Text = 'SecondaryComaY';
            app.SecondarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondarySphericalEditFieldLabel.Text = 'SecondarySpherical';
            app.TetrafoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilXEditFieldLabel.Text = 'TetrafoilX';
            app.TetrafoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilYEditFieldLabel.Text = 'TetrafoilY';
            app.TertiarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.TertiarySphericalEditFieldLabel.Text = 'TertiarySpherical';
            app.QuaternarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.QuaternarySphericalEditFieldLabel.Text = 'QuaternarySpherical';
            app.QuaternarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TertiarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PrimarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PowerEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PistonEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.RadiusEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CenterYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CenterXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CustomphaseTab = uitab(app.TabGroup2);
            app.CustomphaseTab.Title = 'Custom phase';
            app.ImagepathEditFieldLabel = uilabel(app.CustomphaseTab);
            app.ImagepathEditFieldLabel.Text = 'Image path';
            app.PhaseformatDropDownLabel = uilabel(app.CustomphaseTab);
            app.PhaseformatDropDownLabel.Text = 'Phase format';
            app.PhaseformatDropDown = uidropdown(app.CustomphaseTab);
            app.PhaseformatDropDown.Items = {'Preprocessed to uint8', 'Radians'};
            app.PhaseformatDropDown.Value = 'Preprocessed to uint8';
            app.LoadimageButton = uibutton(app.CustomphaseTab, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.BrowseimageButton = uibutton(app.CustomphaseTab, 'push');
            app.BrowseimageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseimageButtonPushed, true);
            app.BrowseimageButton.Text = 'Browse image';
            app.ImagepathEditField = uieditfield(app.CustomphaseTab, 'text');
            app.UploadHologramButtonGenerate = uibutton(app.GenerateHologramTab, 'push');
            app.UploadHologramButtonGenerate.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonGeneratePushedGenerate, true);
            app.UploadHologramButtonGenerate.Text = 'Upload Hologram';
            app.UIAxesGenerateHologram = uiaxes(app.GenerateHologramTab);
            title(app.UIAxesGenerateHologram, 'Hologram phase preview')
            xlabel(app.UIAxesGenerateHologram, 'X pixels')
            ylabel(app.UIAxesGenerateHologram, 'Y pixels')
            app.UIAxesColorLimits = uiaxes(app.GenerateHologramTab);
            title(app.UIAxesColorLimits, 'color limit (pi)')
            zlabel(app.UIAxesColorLimits, 'Z')
            app.UIAxesColorLimits.YTick = [];
            app.AdaptiveOpticsmodalTab = uitab(app.TabGroup);
            app.AdaptiveOpticsmodalTab.Title = 'Adaptive Optics modal';
            app.AOautooptimizationscanPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.AOautooptimizationscanPanel.Title = 'AO auto optimization scan';
            app.NumscanframesOpEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.NumscanframesOpEditField.Limits = [0 Inf];
            app.NumscanframesOpEditField.RoundFractionalValues = 'on';
            app.NumscanframesOpEditField.Editable = 'off';
            app.ContinuescanCheckBox = uicheckbox(app.AOautooptimizationscanPanel);
            app.ContinuescanCheckBox.Text = 'Continue scan';
            app.StartoptimizationButton = uibutton(app.AOautooptimizationscanPanel, 'state');
            app.StartoptimizationButton.ValueChangedFcn = createCallbackFcn(app, @StartoptimizationButtonValueChanged, true);
            app.StartoptimizationButton.Text = 'Start optimization';
            app.PostscandelayEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.PostscandelayEditField.Tooltip = {'0.5 for 16fr averaging; 0.8 for 32fr ave'};
            app.PostscandelayEditField.Value = 0.8;
            app.BeadwindowsizeEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.BeadwindowsizeEditField.Value = 80;
            app.PlotextradeetsCheckBox = uicheckbox(app.AOautooptimizationscanPanel);
            app.PlotextradeetsCheckBox.Text = 'Plot extra deets';
            app.PlotprogressCheckBox = uicheckbox(app.AOautooptimizationscanPanel);
            app.PlotprogressCheckBox.Text = 'Plot progress';
            app.PlotprogressCheckBox.Value = true;
            app.NumiterationsSpinner = uispinner(app.AOautooptimizationscanPanel);
            app.NumiterationsSpinner.Limits = [1 Inf];
            app.NumiterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumiterationsSpinnerValueChanged, true);
            app.NumiterationsSpinner.Value = 40;
            app.DecresegradntimesEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.DecresegradntimesEditField.Limits = [0 Inf];
            app.DecresegradntimesEditField.Value = 1;
            app.ScanallcorreverynframesEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.ScanallcorreverynframesEditField.Limits = [1 Inf];
            app.ScanallcorreverynframesEditField.Value = 500;
            app.RefocussplinesmparamEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.RefocussplinesmparamEditField.Limits = [0 Inf];
            app.RefocussplinesmparamEditField.Value = 0.3;
            app.RefocusnumstepsEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.RefocusnumstepsEditField.Limits = [0 Inf];
            app.RefocusnumstepsEditField.Value = 11;
            app.RefocusdistumEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.RefocusdistumEditField.Limits = [0 Inf];
            app.RefocusdistumEditField.Value = 10;
            app.RefocuseverynframesEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.RefocuseverynframesEditField.Limits = [1 Inf];
            app.RefocuseverynframesEditField.Value = 200;
            app.OptimizationmethodDropDown = uidropdown(app.AOautooptimizationscanPanel);
            app.OptimizationmethodDropDown.Items = {};
            app.OptimizationmethodDropDown.Value = {};
            app.ScanframesdirpathEditField = uieditfield(app.AOautooptimizationscanPanel, 'text');
            app.ScanframesdirpathEditField.Value = '\\PRAIRIE2000\p2f\Yuriy\AO\';
            app.NumiterationsSpinnerLabel = uilabel(app.AOautooptimizationscanPanel);
            app.NumiterationsSpinnerLabel.Text = 'Num iterations';
            app.ScanframesdirpathEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.ScanframesdirpathEditFieldLabel.Text = 'Scan frames dir path';
            app.NumscanframesLabel_2 = uilabel(app.AOautooptimizationscanPanel);
            app.NumscanframesLabel_2.Text = 'Num scan frames';
            app.BeadwindowsizeEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.BeadwindowsizeEditFieldLabel.Text = 'Bead window size';
            app.PostscandelayEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.PostscandelayEditFieldLabel.Text = 'Post scan delay';
            app.OptimizationmethodDropDownLabel = uilabel(app.AOautooptimizationscanPanel);
            app.OptimizationmethodDropDownLabel.Text = 'Optimization method';
            app.RefocuseverynframesEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.RefocuseverynframesEditFieldLabel.Text = 'Refocus every n frames';
            app.ScanallcorreverynframesEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.ScanallcorreverynframesEditFieldLabel.Text = 'Scan all corr every n frames';
            app.DecresegradntimesEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.DecresegradntimesEditFieldLabel.Text = 'Decrese grad n times';
            app.RefocusdistumEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.RefocusdistumEditFieldLabel.Text = 'Refocus dist (um)';
            app.RefocusnumstepsEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.RefocusnumstepsEditFieldLabel.Text = 'Refocus num steps';

            app.RefocussplinesmparamEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.RefocussplinesmparamEditFieldLabel.Text = 'Refocus spline sm param';
            app.ParamsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.ParamsPanel.Title = 'Params';
            app.SavefiletagEditFieldLabel = uilabel(app.ParamsPanel);
            app.SavefiletagEditFieldLabel.Text = 'Save file tag';
            app.ScanspermodeEditFieldLabel = uilabel(app.ParamsPanel);
            app.ScanspermodeEditFieldLabel.Text = 'Scans per mode';
            app.ScanspermodeEditField = uieditfield(app.ParamsPanel, 'numeric');
            app.ScanspermodeEditField.Limits = [1 Inf];
            app.ScanspermodeEditField.ValueChangedFcn = createCallbackFcn(app, @ScanspermodeEditFieldValueChanged, true);
            app.ScanspermodeEditField.Value = 4;
            app.ShufflemodesCheckBox = uicheckbox(app.ParamsPanel);
            app.ShufflemodesCheckBox.Text = 'Shuffle modes';
            app.ShufflemodesCheckBox.Value = true;
            app.SavefiletagEditField = uieditfield(app.ParamsPanel, 'text');
            app.SavefiletagEditField.Value = 'zernike_scan_data';
            app.ZernikeListTable = uitable(app.AdaptiveOpticsmodalTab);
            app.ZernikeListTable.ColumnName = {'idx'; 'Zn'; 'Zm'; 'W range'; 'W step'; 'W num steps'; 'Include'};
            app.ZernikeListTable.RowName = {};
            app.ZernikeListTable.ColumnEditable = [false false false true true true true];
            app.ZernikeListTable.CellEditCallback = createCallbackFcn(app, @ZernikeListTableCellEdit, true);
            app.ZernikeModestoscanadjustweightsifneededLabel = uilabel(app.AdaptiveOpticsmodalTab);
            app.ZernikeModestoscanadjustweightsifneededLabel.Text = 'Zernike Modes to scan (adjust weights if needed)';
            app.FillzerniketablePanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.FillzerniketablePanel.Title = 'Fill zernike table';
            app.FillmodestableButton = uibutton(app.FillzerniketablePanel, 'push');
            app.FillmodestableButton.ButtonPushedFcn = createCallbackFcn(app, @FillmodestableButtonPushed, true);
            app.FillmodestableButton.Text = 'Fill modes table';
            app.AOignoreallsphericalCheckBox = uicheckbox(app.FillzerniketablePanel);
            app.AOignoreallsphericalCheckBox.Text = 'AO ignore all spherical';
            app.AOignoreallsphericalCheckBox.Value = true;
            app.MaxZnEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.MaxZnEditFieldLabel.Text = 'Max Zn';
            app.WeightrangeEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.WeightrangeEditFieldLabel.Text = 'Weight range';
            app.NumwstepsEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.NumwstepsEditFieldLabel.Text = 'Num w steps';
            app.AOignoresphericalmodeCheckBox = uicheckbox(app.FillzerniketablePanel);
            app.AOignoresphericalmodeCheckBox.Text = 'AO ignore spherical mode';
            app.AOignoresphericalmodeCheckBox.Value = true;
            app.WregfactorEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WregfactorEditField.Limits = [0 1];
            app.WregfactorEditField.Value = 0.002;
            app.WsplinesmparamEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WsplinesmparamEditField.Limits = [0 1];
            app.WsplinesmparamEditField.Value = 0.1;
            app.NumwstepsEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.NumwstepsEditField.RoundFractionalValues = 'on';
            app.NumwstepsEditField.Value = 13;
            app.WeightrangeEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WeightrangeEditField.Value = 1.5;
            app.MaxZnEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.MaxZnEditField.Value = 4;
            app.MinZnEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.MinZnEditFieldLabel.Text = 'Min Zn';
            app.MinZnEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WsplinesmparamEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.WsplinesmparamEditFieldLabel.Text = 'W spline sm param';
            app.WregfactorLabel = uilabel(app.FillzerniketablePanel);
            app.WregfactorLabel.Text = 'W reg factor';
            app.TestHologramandweightsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.TestHologramandweightsPanel.Title = 'Test Hologram and weights';
            app.PlotHologramButton = uibutton(app.TestHologramandweightsPanel, 'push');
            app.PlotHologramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHologramButtonPushed, true);
            app.PlotHologramButton.Text = 'Plot Hologram';
            app.UploadHologramButton_2 = uibutton(app.TestHologramandweightsPanel, 'push');
            app.UploadHologramButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButton_2Pushed, true);
            app.UploadHologramButton_2.Text = 'Upload Hologram';
            app.ModeindexEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.ModeindexEditFieldLabel.Text = 'Mode index';
            app.weightEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.weightEditFieldLabel.Text = 'weight';
            app.weightEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.weightEditField.Value = 1;
            app.ModeindexEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.ModeindexEditField.Limits = [1 Inf];
            app.ModeindexEditField.RoundFractionalValues = 'on';
            app.ModeindexEditField.Value = 1;
            app.AOregularscanzernikepolPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.AOregularscanzernikepolPanel.Title = 'AO regular scan zernike pol';
            app.NumscanvolumesEditFieldLabel = uilabel(app.AOregularscanzernikepolPanel);
            app.NumscanvolumesEditFieldLabel.Text = 'Num scan volumes';
            app.NumscanvolumesEditField = uieditfield(app.AOregularscanzernikepolPanel, 'numeric');
            app.NumscanvolumesEditField.Limits = [0 Inf];
            app.NumscanvolumesEditField.RoundFractionalValues = 'on';
            app.NumscanvolumesEditField.Editable = 'off';
            app.NumscanframesLabel = uilabel(app.AOregularscanzernikepolPanel);
            app.NumscanframesLabel.Text = 'Num scan frames';
            app.NumscanframesEditField = uieditfield(app.AOregularscanzernikepolPanel, 'numeric');
            app.NumscanframesEditField.Limits = [0 Inf];
            app.NumscanframesEditField.RoundFractionalValues = 'on';
            app.NumscanframesEditField.Editable = 'off';
            app.ZernikeReadyLamp = uilamp(app.AOregularscanzernikepolPanel);
            app.ScanZernikeButton = uibutton(app.AOregularscanzernikepolPanel, 'state');
            app.ScanZernikeButton.ValueChangedFcn = createCallbackFcn(app, @ScanZernikeButtonValueChanged, true);
            app.ScanZernikeButton.Text = 'Scan Zernike';
            app.XYZalgorithmTab = uitab(app.TabGroup);
            app.XYZalgorithmTab.Title = 'XYZ algorithm';
            app.DiskradiusumEditFieldLabel = uilabel(app.XYZalgorithmTab);
            app.DiskradiusumEditFieldLabel.Text = 'Disk  radius (um)';
            app.DiskradiusumEditField = uieditfield(app.XYZalgorithmTab, 'numeric');
            app.DiskradiusumEditField.Limits = [1 Inf];
            app.DiskradiusumEditField.Value = 5;
            app.SLMimagepredictionwithfftPanel = uipanel(app.XYZalgorithmTab);
            app.SLMimagepredictionwithfftPanel.Title = 'SLM image prediction (with fft)';
            app.UsegaussianbeamampCheckBox = uicheckbox(app.SLMimagepredictionwithfftPanel);
            app.UsegaussianbeamampCheckBox.Text = 'Use gaussian beam amp';
            app.UsegaussianbeamampCheckBox.Value = true;
            app.MeadowlarkGSalgorithmPanel = uipanel(app.XYZalgorithmTab);
            app.MeadowlarkGSalgorithmPanel.Title = 'Meadowlark GS algorithm';
            app.GSnumiterationsEditFieldLabel = uilabel(app.MeadowlarkGSalgorithmPanel);
            app.GSnumiterationsEditFieldLabel.Text = 'GS num iterations';
            app.GSnumiterationsEditField = uieditfield(app.MeadowlarkGSalgorithmPanel, 'numeric');
            app.GSnumiterationsEditField.Value = 50;
            app.GSzfactorEditFieldLabel = uilabel(app.MeadowlarkGSalgorithmPanel);
            app.GSzfactorEditFieldLabel.Text = 'GS z factor';
            app.GSzfactorEditField = uieditfield(app.MeadowlarkGSalgorithmPanel, 'numeric');
            app.GSzfactorEditField.Limits = [0 Inf];
            app.GSzfactorEditField.Value = 1;
            app.ImageGenverEditFieldLabel = uilabel(app.MeadowlarkGSalgorithmPanel);
            app.ImageGenverEditFieldLabel.Text = 'ImageGen ver';
            app.ImageGenverEditField = uieditfield(app.MeadowlarkGSalgorithmPanel, 'text');
            app.ImageGenverEditField.Editable = 'off';
            app.SLMCalibrationTab = uitab(app.TabGroup);
            app.SLMCalibrationTab.Title = 'SLM Calibration';
            app.LUTcalibrationdataPanel = uipanel(app.SLMCalibrationTab);
            app.LUTcalibrationdataPanel.Enable = 'off';
            app.LUTcalibrationdataPanel.Title = 'LUT calibration data';
            app.LUTcalibrationdataPanel.Visible = 'off';
            app.BitDepthEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.BitDepthEditFieldLabel.Text = 'Bit Depth';
            app.BitDepthEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.BitDepthEditField.Limits = [1 256];
            app.BitDepthEditField.RoundFractionalValues = 'on';
            app.BitDepthEditField.ValueChangedFcn = createCallbackFcn(app, @BitDepthEditFieldValueChanged, true);
            app.BitDepthEditField.Value = 256;
            app.NumRegionsEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.NumRegionsEditFieldLabel.Text = 'Num Regions';
            app.NumRegionsEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.NumRegionsEditField.Limits = [1 Inf];
            app.NumRegionsEditField.RoundFractionalValues = 'on';
            app.NumRegionsEditField.ValueChangedFcn = createCallbackFcn(app, @NumRegionsEditFieldValueChanged, true);
            app.NumRegionsEditField.Value = 1;
            app.PixelsPerStripeEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.PixelsPerStripeEditFieldLabel.Text = 'Pixels Per Stripe';
            app.PixelsPerStripeEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.PixelsPerStripeEditField.Limits = [1 Inf];
            app.PixelsPerStripeEditField.Value = 8;
            app.AnalysissavefilenameEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.AnalysissavefilenameEditFieldLabel.Text = 'Analysis save file name';
            app.AnalysissavefilenameEditField = uieditfield(app.LUTcalibrationdataPanel, 'text');
            app.AnalysissavefilenameEditField.Value = '940_slm5221_maitai2';
            app.RunLUTcalibrationButton = uibutton(app.LUTcalibrationdataPanel, 'state');
            app.RunLUTcalibrationButton.ValueChangedFcn = createCallbackFcn(app, @RunLUTcalibrationButtonValueChanged, true);
            app.RunLUTcalibrationButton.Text = 'Run LUT calibration';
            app.AnalyzeLUTdataButton = uibutton(app.LUTcalibrationdataPanel, 'push');
            app.AnalyzeLUTdataButton.Text = 'Analyze LUT data';
            app.RunLUTcalibReadyLamp = uilamp(app.LUTcalibrationdataPanel);
            app.BaselinepixelvalueEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.BaselinepixelvalueEditFieldLabel.Text = 'Baseline pixel value';
            app.BaselinepixelvalueEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.BaselinepixelvalueEditField.Limits = [0 255];
            app.BaselinepixelvalueEditField.RoundFractionalValues = 'on';
            app.TotalframesEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.TotalframesEditFieldLabel.Text = 'Total frames';
            app.TotalframesEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.TotalframesEditField.Editable = 'off';
            app.ButtonGroup = uibuttongroup(app.LUTcalibrationdataPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.EndofframeButton = uitogglebutton(app.ButtonGroup);
            app.EndofframeButton.Text = 'End of frame';
            app.EndofframeButton.Value = true;
            app.TLDCcamButton = uitogglebutton(app.ButtonGroup);
            app.TLDCcamButton.Text = 'TLDC cam';
            app.LateralcalibrationButton = uibutton(app.SLMCalibrationTab, 'push');
            app.LateralcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LateralcalibrationButtonPushed, true);
            app.LateralcalibrationButton.Text = 'Lateral calibration';
            app.PointWeightcorrectionPanel = uipanel(app.SLMCalibrationTab);
            app.PointWeightcorrectionPanel.Title = 'Point Weight correction';
            app.PlotpwcorrectionButton = uibutton(app.PointWeightcorrectionPanel, 'push');
            app.PlotpwcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotpwcorrectionButtonPushed, true);
            app.PlotpwcorrectionButton.Text = 'Plot pw correction';
            app.smoothstdEditFieldLabel = uilabel(app.PointWeightcorrectionPanel);
            app.smoothstdEditFieldLabel.Text = 'smooth std';
            app.PWsqrt2pCheckBox = uicheckbox(app.PointWeightcorrectionPanel);
            app.PWsqrt2pCheckBox.Text = 'sqrt (2p)';
            app.MincorrthreshEditFieldLabel = uilabel(app.PointWeightcorrectionPanel);
            app.MincorrthreshEditFieldLabel.Text = 'Min corr thresh';
            app.PWmincorrthreshEditField = uieditfield(app.PointWeightcorrectionPanel, 'numeric');
            app.PWsmoothstdEditField = uieditfield(app.PointWeightcorrectionPanel, 'numeric');
            app.PWsmoothstdEditField.Value = 1;
            app.AOPanel = uipanel(app.SLMCalibrationTab);
            app.AOPanel.Title = 'AO';
            app.IgnoreallsphericalCheckBox = uicheckbox(app.AOPanel);
            app.IgnoreallsphericalCheckBox.Text = 'Ignore all spherical';
            app.PlotextraCheckBox = uicheckbox(app.AOPanel);
            app.PlotextraCheckBox.Text = 'Plot extra';
            app.modestofitEditFieldLabel = uilabel(app.AOPanel);
            app.modestofitEditFieldLabel.Text = 'modes to fit';
            app.FitmethodDropDownLabel = uilabel(app.AOPanel);
            app.FitmethodDropDownLabel.Text = 'Fit method';
            app.splinesmparam01EditFieldLabel = uilabel(app.AOPanel);
            app.splinesmparam01EditFieldLabel.Text = 'spline sm param [0, 1]';
            app.PlotfitCheckBox = uicheckbox(app.AOPanel);
            app.PlotfitCheckBox.Text = 'Plot fit';
            app.CompensatezCheckBox = uicheckbox(app.AOPanel);
            app.CompensatezCheckBox.Text = 'Compensate z';
            app.Ignore0CheckBox = uicheckbox(app.AOPanel);
            app.Ignore0CheckBox.Text = 'Ignore 0';
            app.Constrainz0CheckBox = uicheckbox(app.AOPanel);
            app.Constrainz0CheckBox.Text = 'Constrain z0';
            app.save_fit_weightsCheckBox = uicheckbox(app.AOPanel);
            app.save_fit_weightsCheckBox.Text = 'save_fit_weights';
            app.splinesmparam01EditField = uieditfield(app.AOPanel, 'numeric');
            app.splinesmparam01EditField.Value = 0.0001;
            app.modestofitEditField = uieditfield(app.AOPanel, 'text');
            app.FitmethodDropDown = uidropdown(app.AOPanel);
            app.FitmethodDropDown.Items = {};
            app.FitmethodDropDown.Value = {};
            app.FitAOcorretionsButton = uibutton(app.AOPanel, 'push');
            app.FitAOcorretionsButton.ButtonPushedFcn = createCallbackFcn(app, @FitAOcorretionsButtonPushed, true);
            app.FitAOcorretionsButton.Text = 'Fit AO corretions';
            app.ZcompthreshumEditFieldLabel = uilabel(app.AOPanel);
            app.ZcompthreshumEditFieldLabel.Text = 'Z comp thresh (um)';
            app.ZcompthreshumEditField = uieditfield(app.AOPanel, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.CloseallfigsButton = uibutton(app.Panel, 'push');
            app.CloseallfigsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfigsButtonPushed, true);
            app.CloseallfigsButton.Text = 'Close all figs';
            app.SavecurrentlutButton = uibutton(app.Panel, 'push');
            app.SavecurrentlutButton.ButtonPushedFcn = createCallbackFcn(app, @SavecurrentlutButtonPushed, true);
            app.SavecurrentlutButton.Text = 'Save current lut';
            app.ViewcurrentlutButton = uibutton(app.Panel, 'push');
            app.ViewcurrentlutButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcurrentlutButtonPushed, true);
            app.ViewcurrentlutButton.Text = 'View current lut';
            app.ViewcurrentAOButton = uibutton(app.Panel, 'push');
            app.ViewcurrentAOButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcurrentAOButtonPushed, true);
            app.ViewcurrentAOButton.Text = 'View current AO';
            app.ViewcurrentphaseButton = uibutton(app.Panel, 'push');
            app.ViewcurrentphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcurrentphaseButtonPushed, true);
            app.ViewcurrentphaseButton.Text = 'View current phase';
            app.BlankSLMButton = uibutton(app.Panel, 'push');
            app.BlankSLMButton.ButtonPushedFcn = createCallbackFcn(app, @BlankSLMButtonPushed, true);
            app.BlankSLMButton.Tooltip = {'during blank corrections are not applied (LUT or AO)'};
            app.BlankSLMButton.Text = 'Blank SLM';
            app.CurrentregionDropDown = uidropdown(app.Panel);
            app.CurrentregionDropDown.Items = {};
            app.CurrentregionDropDown.Value = {};
            app.ZOsuppressionLamp = uilamp(app.Panel);
            app.ApplyZOsuppressionButton = uibutton(app.Panel, 'state');
            app.ApplyZOsuppressionButton.ValueChangedFcn = createCallbackFcn(app, @ApplyZOsuppressionButtonValueChanged, true);
            app.ApplyZOsuppressionButton.Text = {'Apply ZO'; 'suppression'};
            app.PWcorrectionLamp = uilamp(app.Panel);
            app.ApplyPWcorrectionButton = uibutton(app.Panel, 'state');
            app.ApplyPWcorrectionButton.ValueChangedFcn = createCallbackFcn(app, @ApplyPWcorrectionButtonValueChanged, true);
            app.ApplyPWcorrectionButton.Tooltip = {'Point Weight (PW) corrections. These are measured correction for reduction in SLM efficiency as points are deflected laterally. These can be measured by deflecting points around a grid and measuring the fluoresence intensity with a camera.'};
            app.ApplyPWcorrectionButton.Text = {'Apply PW'; 'correction'};
            app.InitializeAOLamp = uilamp(app.Panel);
            app.ApplyAOcorrectionButton = uibutton(app.Panel, 'state');
            app.ApplyAOcorrectionButton.ValueChangedFcn = createCallbackFcn(app, @ApplyAOcorrectionButtonValueChanged, true);
            app.ApplyAOcorrectionButton.Text = {'Apply AO'; 'correction'};
            app.XYZcalibrationLamp = uilamp(app.Panel);
            app.ApplyXYZcalibrationButton = uibutton(app.Panel, 'state');
            app.ApplyXYZcalibrationButton.ValueChangedFcn = createCallbackFcn(app, @ApplyXYZcalibrationButtonValueChanged, true);
            app.ApplyXYZcalibrationButton.Text = {'Apply XYZ'; 'calibration'};
            app.InitializeDAQLamp = uilamp(app.Panel);
            app.InitializeDAQButton = uibutton(app.Panel, 'push');
            app.InitializeDAQButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDAQButtonPushed, true);
            app.InitializeDAQButton.Text = 'Initialize DAQ';
            app.ActivateSLMLamp = uilamp(app.Panel);
            app.ActivateSLMButton = uibutton(app.Panel, 'state');
            app.ActivateSLMButton.ValueChangedFcn = createCallbackFcn(app, @ActivateSLMButtonValueChanged, true);
            app.ActivateSLMButton.Text = 'Activate SLM';
            app.CurrentregionDropDownLabel = uilabel(app.Panel);
            app.CurrentregionDropDownLabel.Text = 'Current region';
            app.ViewcurrentimageButton = uibutton(app.Panel, 'push');
            app.ViewcurrentimageButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcurrentimageButtonPushed, true);
            app.ViewcurrentimageButton.Text = 'View current image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end