classdef sg_pattern_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        imagedirEditFieldLabel      matlab.ui.control.Label
        ZdepthSpinnerLabel          matlab.ui.control.Label
        PatternSpinnerLabel         matlab.ui.control.Label
        ZoomEditFieldLabel          matlab.ui.control.Label
        ClearbkgimButton            matlab.ui.control.Button
        BkgimageonButton            matlab.ui.control.StateButton
        rightclickremoveButton      matlab.ui.control.StateButton
        leftclickaddButton          matlab.ui.control.StateButton
        UpdateplotButton            matlab.ui.control.Button
        ZoomEditField               matlab.ui.control.NumericEditField
        ZdepthSpinner               matlab.ui.control.Spinner
        PatternSpinner              matlab.ui.control.Spinner
        LoadimagesButton            matlab.ui.control.Button
        AdddirButton                matlab.ui.control.Button
        imagedirEditField           matlab.ui.control.EditField
        ClearallptsButton           matlab.ui.control.Button
        PlotallpatternsCheckBox     matlab.ui.control.CheckBox
        AddgridButton               matlab.ui.control.Button
        numXEditFieldLabel          matlab.ui.control.Label
        numXEditField               matlab.ui.control.NumericEditField
        numYEditFieldLabel          matlab.ui.control.Label
        numYEditField               matlab.ui.control.NumericEditField
        spacingXEditFieldLabel      matlab.ui.control.Label
        spacingXEditField           matlab.ui.control.NumericEditField
        spacingYEditFieldLabel      matlab.ui.control.Label
        spacingYEditField           matlab.ui.control.NumericEditField
        shiftXEditFieldLabel        matlab.ui.control.Label
        shiftXEditField             matlab.ui.control.NumericEditField
        shiftYEditFieldLabel        matlab.ui.control.Label
        shiftYEditField             matlab.ui.control.NumericEditField
        SamepatternCheckBox         matlab.ui.control.CheckBox
        NewpatternCheckBox          matlab.ui.control.CheckBox
        ListgroupsTextAreaLabel     matlab.ui.control.Label
        ListgroupsTextArea          matlab.ui.control.TextArea
        SavegroupsButton            matlab.ui.control.Button
        IndextagCheckBox            matlab.ui.control.CheckBox
        PatterntagCheckBox          matlab.ui.control.CheckBox
        GrouptagCheckBox            matlab.ui.control.CheckBox
        PatterngroupEditFieldLabel  matlab.ui.control.Label
        PatterngroupEditField       matlab.ui.control.EditField
        SamplecombinationsButton    matlab.ui.control.Button
        FromgroupSpinnerLabel       matlab.ui.control.Label
        FromgroupSpinner            matlab.ui.control.Spinner
        MinpatsizeEditFieldLabel    matlab.ui.control.Label
        MinpatsizeEditField         matlab.ui.control.NumericEditField
        MaxpatsizeEditFieldLabel    matlab.ui.control.Label
        MaxpatsizeEditField         matlab.ui.control.NumericEditField
        VMaxSlider                  matlab.ui.control.Slider
        VMaxLabel                   matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    
    properties (Access = public)
        app_main; % Description
        data;
    end
    
    properties (Access = private)
        zdepth_mutex = true; % Description
        current_colormap = 'parula'; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_main)
            app.app_main = app_main;
            f_sg_pp_initialize(app);
        end

        % Button pushed function: AdddirButton
        function AdddirButtonPushed(app, event)
            f_sg_pp_add_dir(app);
        end

        % Button pushed function: LoadimagesButton
        function LoadimagesButtonPushed(app, event)
            f_sg_pp_load_images(app);
            f_sg_pp_init_z_depth_spinner(app);
        end

        % Callback function
        function patternDropDownValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
        end

        % Button pushed function: UpdateplotButton
        function UpdateplotButtonPushed(app, event)
            f_sg_pp_update_pat_plot(app);
        end

        % Value changed function: ZdepthSpinner
        function ZdepthSpinnerValueChanged(app, event)
            if app.zdepth_mutex 
                % matlab does a terrible job of the preventing the UI 
                % spinners from having race conditions, so using a
                % sync primitive
                app.zdepth_mutex = false;
                f_sg_pp_update_z_depth(app, event);
                f_sg_pp_update_bkg_im(app);
                f_sg_pp_update_pat_plot(app);
                app.app_main.pp_ops.z_depth = app.ZdepthSpinner.Value;
                app.zdepth_mutex = true;
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            f_sg_pp_button_down(app, event);
        end

        % Value changed function: BkgimageonButton
        function BkgimageonButtonValueChanged(app, event)
            f_sg_pp_update_bkg_im(app);
        end

        % Button pushed function: ClearbkgimButton
        function ClearbkgimButtonPushed(app, event)
            f_sg_pp_clear_bkg_im(app);
        end

        % Value changed function: PatternSpinner
        function PatternSpinnerValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
            app.app_main.pp_ops.pattern = app.PatternSpinner.Value;
        end

        % Value changed function: ZoomEditField
        function ZoomEditFieldValueChanged(app, event)
            f_sg_pp_update_axes(app);
            app.app_main.pp_ops.zoom = app.ZoomEditField.Value;
        end

        % Button pushed function: AddgridButton
        function AddgridButtonPushed(app, event)
            f_sg_pp_add_grid(app);
        end

        % Button pushed function: ClearallptsButton
        function ClearallptsButtonPushed(app, event)
            f_sg_pp_clear_pts(app);
        end

        % Value changed function: PlotallpatternsCheckBox
        function PlotallpatternsCheckBoxValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
            app.app_main.pp_ops.plot_all_pat_check = app.PlotallpatternsCheckBox.Value;
        end

        % Value changed function: numXEditField
        function numXEditFieldValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
            app.app_main.pp_ops.numX = app.numXEditField.Value;
        end

        % Value changed function: numYEditField
        function numYEditFieldValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
            app.app_main.pp_ops.numY = app.numYEditField.Value;
        end

        % Value changed function: spacingXEditField
        function spacingXEditFieldValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
            app.app_main.pp_ops.spacingX = app.spacingXEditField.Value;
        end

        % Value changed function: spacingYEditField
        function spacingYEditFieldValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
            app.app_main.pp_ops.spacingY = app.spacingYEditField.Value;
        end

        % Value changed function: shiftXEditField
        function shiftXEditFieldValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
            app.app_main.pp_ops.shiftX = app.shiftXEditField.Value;
        end

        % Value changed function: shiftYEditField
        function shiftYEditFieldValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
            app.app_main.pp_ops.shiftY = app.shiftXEditField.Value;
        end

        % Button pushed function: SavegroupsButton
        function SavegroupsButtonPushed(app, event)
            f_sg_pp_save_groups(app);
        end

        % Value changed function: IndextagCheckBox
        function IndextagCheckBoxValueChanged(app, event)
            app.app_main.pp_ops.index_tag_check = app.IndextagCheckBox.Value;
        end

        % Value changed function: PatterntagCheckBox
        function PatterntagCheckBoxValueChanged(app, event)
            app.app_main.pp_ops.pattern_tag_check = app.PatterntagCheckBox.Value;
        end

        % Value changed function: GrouptagCheckBox
        function GrouptagCheckBoxValueChanged(app, event)
            app.app_main.pp_ops.group_tag_check = app.GrouptagCheckBox.Value;
        end

        % Value changed function: NewpatternCheckBox
        function NewpatternCheckBoxValueChanged(app, event)
            app.app_main.pp_ops.new_pat_check = app.NewpatternCheckBox.Value;
        end

        % Value changed function: SamepatternCheckBox
        function SamepatternCheckBoxValueChanged(app, event)
            app.app_main.pp_ops.same_pat_check = app.SamepatternCheckBox.Value;
        end

        % Value changed function: imagedirEditField
        function imagedirEditFieldValueChanged(app, event)
            app.app_main.pp_ops.image_dir = app.imagedirEditField.Value;
        end

        % Button pushed function: SamplecombinationsButton
        function SamplecombinationsButtonPushed(app, event)
            f_sg_pp_sample_comb(app);
        end

        % Value changed function: VMaxSlider
        function VMaxSliderValueChanged(app, event)
            value_cutoff = app.VMaxSlider.Value;
            f_sg_pp_vmax_cutoff(app, double(value_cutoff));
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.current_colormap = app.DropDown.Value;
            f_sg_pp_update_colormap(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.imagedirEditFieldLabel = uilabel(app.UIFigure);
            app.imagedirEditFieldLabel.Text = 'image dir';
            app.ZdepthSpinnerLabel = uilabel(app.UIFigure);
            app.ZdepthSpinnerLabel.Text = 'Z depth';
            app.PatternSpinnerLabel = uilabel(app.UIFigure);
            app.PatternSpinnerLabel.Text = 'Pattern';
            app.ZoomEditFieldLabel = uilabel(app.UIFigure);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.ClearbkgimButton = uibutton(app.UIFigure, 'push');
            app.ClearbkgimButton.ButtonPushedFcn = createCallbackFcn(app, @ClearbkgimButtonPushed, true);
            app.ClearbkgimButton.Text = 'Clear bkg im';
            app.BkgimageonButton = uibutton(app.UIFigure, 'state');
            app.BkgimageonButton.ValueChangedFcn = createCallbackFcn(app, @BkgimageonButtonValueChanged, true);
            app.BkgimageonButton.Text = 'Bkg image on';
            app.BkgimageonButton.Value = true;
            app.rightclickremoveButton = uibutton(app.UIFigure, 'state');
            app.rightclickremoveButton.Text = 'right click remove';
            app.rightclickremoveButton.Value = true;
            app.leftclickaddButton = uibutton(app.UIFigure, 'state');
            app.leftclickaddButton.Text = 'left click add';
            app.leftclickaddButton.Value = true;
            app.UpdateplotButton = uibutton(app.UIFigure, 'push');
            app.UpdateplotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotButtonPushed, true);
            app.UpdateplotButton.Text = 'Update plot';
            app.ZoomEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZoomEditField.Limits = [0.01 Inf];
            app.ZoomEditField.ValueChangedFcn = createCallbackFcn(app, @ZoomEditFieldValueChanged, true);
            app.ZoomEditField.Value = 1;
            app.ZdepthSpinner = uispinner(app.UIFigure);
            app.ZdepthSpinner.ValueChangedFcn = createCallbackFcn(app, @ZdepthSpinnerValueChanged, true);
            app.ZdepthSpinner.Tooltip = {''};
            app.PatternSpinner = uispinner(app.UIFigure);
            app.PatternSpinner.Limits = [1 Inf];
            app.PatternSpinner.RoundFractionalValues = 'on';
            app.PatternSpinner.ValueChangedFcn = createCallbackFcn(app, @PatternSpinnerValueChanged, true);
            app.PatternSpinner.Tooltip = {'Add all background images to a folder with a tag in file name (''_z##) corresponding to z depths'};
            app.PatternSpinner.Value = 1;
            app.LoadimagesButton = uibutton(app.UIFigure, 'push');
            app.LoadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesButtonPushed, true);
            app.LoadimagesButton.Tooltip = {'Add all background images to a folder with a tag in file name (''_z##) corresponding to z depths and load them'};
            app.LoadimagesButton.Text = 'Load images';
            app.AdddirButton = uibutton(app.UIFigure, 'push');
            app.AdddirButton.ButtonPushedFcn = createCallbackFcn(app, @AdddirButtonPushed, true);
            app.AdddirButton.Tooltip = {'Add all background images to a folder with a tag in file name (''_z##) corresponding to z depths and load them'};
            app.AdddirButton.Text = 'Add dir';
            app.imagedirEditField = uieditfield(app.UIFigure, 'text');
            app.imagedirEditField.ValueChangedFcn = createCallbackFcn(app, @imagedirEditFieldValueChanged, true);
            app.ClearallptsButton = uibutton(app.UIFigure, 'push');
            app.ClearallptsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallptsButtonPushed, true);
            app.ClearallptsButton.Text = 'Clear all pts';
            app.PlotallpatternsCheckBox = uicheckbox(app.UIFigure);
            app.PlotallpatternsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotallpatternsCheckBoxValueChanged, true);
            app.PlotallpatternsCheckBox.Text = 'Plot all patterns';
            app.AddgridButton = uibutton(app.UIFigure, 'push');
            app.AddgridButton.ButtonPushedFcn = createCallbackFcn(app, @AddgridButtonPushed, true);
            app.AddgridButton.Text = 'Add grid';
            app.numXEditFieldLabel = uilabel(app.UIFigure);
            app.numXEditFieldLabel.Text = 'num X';
            app.numXEditField = uieditfield(app.UIFigure, 'numeric');
            app.numXEditField.ValueChangedFcn = createCallbackFcn(app, @numXEditFieldValueChanged, true);
            app.numXEditField.Value = 5;
            app.numYEditFieldLabel = uilabel(app.UIFigure);
            app.numYEditFieldLabel.Text = 'num Y';
            app.numYEditField = uieditfield(app.UIFigure, 'numeric');
            app.numYEditField.ValueChangedFcn = createCallbackFcn(app, @numYEditFieldValueChanged, true);
            app.numYEditField.Value = 5;
            app.spacingXEditFieldLabel = uilabel(app.UIFigure);
            app.spacingXEditFieldLabel.Text = 'spacing X';
            app.spacingXEditField = uieditfield(app.UIFigure, 'numeric');
            app.spacingXEditField.ValueChangedFcn = createCallbackFcn(app, @spacingXEditFieldValueChanged, true);
            app.spacingXEditField.Value = 100;
            app.spacingYEditFieldLabel = uilabel(app.UIFigure);
            app.spacingYEditFieldLabel.Text = 'spacing Y';
            app.spacingYEditField = uieditfield(app.UIFigure, 'numeric');
            app.spacingYEditField.ValueChangedFcn = createCallbackFcn(app, @spacingYEditFieldValueChanged, true);
            app.spacingYEditField.Value = 100;
            app.shiftXEditFieldLabel = uilabel(app.UIFigure);
            app.shiftXEditFieldLabel.Text = 'shift X';
            app.shiftXEditField = uieditfield(app.UIFigure, 'numeric');
            app.shiftXEditField.ValueChangedFcn = createCallbackFcn(app, @shiftXEditFieldValueChanged, true);
            app.shiftYEditFieldLabel = uilabel(app.UIFigure);
            app.shiftYEditFieldLabel.Text = 'shift Y';
            app.shiftYEditField = uieditfield(app.UIFigure, 'numeric');
            app.shiftYEditField.ValueChangedFcn = createCallbackFcn(app, @shiftYEditFieldValueChanged, true);
            app.SamepatternCheckBox = uicheckbox(app.UIFigure);
            app.SamepatternCheckBox.ValueChangedFcn = createCallbackFcn(app, @SamepatternCheckBoxValueChanged, true);
            app.SamepatternCheckBox.Text = 'Same pattern';
            app.NewpatternCheckBox = uicheckbox(app.UIFigure);
            app.NewpatternCheckBox.ValueChangedFcn = createCallbackFcn(app, @NewpatternCheckBoxValueChanged, true);
            app.NewpatternCheckBox.Text = 'New pattern';
            app.ListgroupsTextAreaLabel = uilabel(app.UIFigure);
            app.ListgroupsTextAreaLabel.Text = 'List groups';
            app.ListgroupsTextArea = uitextarea(app.UIFigure);
            app.SavegroupsButton = uibutton(app.UIFigure, 'push');
            app.SavegroupsButton.ButtonPushedFcn = createCallbackFcn(app, @SavegroupsButtonPushed, true);
            app.SavegroupsButton.Text = 'Save groups';
            app.IndextagCheckBox = uicheckbox(app.UIFigure);
            app.IndextagCheckBox.ValueChangedFcn = createCallbackFcn(app, @IndextagCheckBoxValueChanged, true);
            app.IndextagCheckBox.Text = 'Index tag';
            app.PatterntagCheckBox = uicheckbox(app.UIFigure);
            app.PatterntagCheckBox.ValueChangedFcn = createCallbackFcn(app, @PatterntagCheckBoxValueChanged, true);
            app.PatterntagCheckBox.Text = 'Pattern tag';
            app.GrouptagCheckBox = uicheckbox(app.UIFigure);
            app.GrouptagCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrouptagCheckBoxValueChanged, true);
            app.GrouptagCheckBox.Text = 'Group tag';
            app.PatterngroupEditFieldLabel = uilabel(app.UIFigure);
            app.PatterngroupEditFieldLabel.Text = 'Pattern group';
            app.PatterngroupEditField = uieditfield(app.UIFigure, 'text');
            app.PatterngroupEditField.Editable = 'off';
            app.SamplecombinationsButton = uibutton(app.UIFigure, 'push');
            app.SamplecombinationsButton.ButtonPushedFcn = createCallbackFcn(app, @SamplecombinationsButtonPushed, true);
            app.SamplecombinationsButton.Text = 'Sample combinations';
            app.FromgroupSpinnerLabel = uilabel(app.UIFigure);
            app.FromgroupSpinnerLabel.Text = 'From group';
            app.FromgroupSpinner = uispinner(app.UIFigure);
            app.MinpatsizeEditFieldLabel = uilabel(app.UIFigure);
            app.MinpatsizeEditFieldLabel.Text = 'Min pat size';
            app.MinpatsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinpatsizeEditField.Value = 2;
            app.MaxpatsizeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxpatsizeEditFieldLabel.Text = 'Max pat size';
            app.MaxpatsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxpatsizeEditField.Value = 2;
            app.VMaxSlider = uislider(app.UIFigure);
            app.VMaxSlider.MajorTicks = [];
            app.VMaxSlider.MajorTickLabels = {''};
            app.VMaxSlider.ValueChangedFcn = createCallbackFcn(app, @VMaxSliderValueChanged, true);
            app.VMaxSlider.MinorTicks = [];
            app.VMaxSlider.Value = 100;
            app.VMaxLabel = uilabel(app.UIFigure);
            app.VMaxLabel.Text = 'V-Max';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'parula', 'jet', 'turbo', 'L1', 'L17', 'L20'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'parula';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.SavegroupsButton.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sg_pattern_plot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end